classdef DLTdv8a < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    DLTdv8appUIFigure               matlab.ui.Figure
    ProjectMenu                     matlab.ui.container.Menu
    NewMenu                         matlab.ui.container.Menu
    LoadMenu                        matlab.ui.container.Menu
    SaveMenu                        matlab.ui.container.Menu
    SaveasMenu                      matlab.ui.container.Menu
    ExporttoworkspaceMenu           matlab.ui.container.Menu
    QuitMenu                        matlab.ui.container.Menu
    PointsMenu                      matlab.ui.container.Menu
    ExportMenu                      matlab.ui.container.Menu
    LoadPointsMenu                  matlab.ui.container.Menu
    FlipverticalMenu                matlab.ui.container.Menu
    MetadataeditorMenu              matlab.ui.container.Menu
    DMenu                           matlab.ui.container.Menu
    LoadnewDLTcoefficientsMenu      matlab.ui.container.Menu
    Recompute3DpointsMenu           matlab.ui.container.Menu
    FlipDLTcoefficientsMenu         matlab.ui.container.Menu
    DeepLearningMenu                matlab.ui.container.Menu
    ManagedatastoresMenu            matlab.ui.container.Menu
    ManagenetworksMenu              matlab.ui.container.Menu
    ApplynetworkMenu                matlab.ui.container.Menu
    CheckgraphicscardMenu           matlab.ui.container.Menu
    UtilitiesMenu                   matlab.ui.container.Menu
    StartDLTcalibrationtoolMenu     matlab.ui.container.Menu
    Set2DscaleandoriginMenu         matlab.ui.container.Menu
    ExportDLTcoefficientsMenu       matlab.ui.container.Menu
    ClearmoviecacheMenu             matlab.ui.container.Menu
    ForcemovieseekingMenu           matlab.ui.container.Menu
    HelpMenu                        matlab.ui.container.Menu
    ListofmouseandkeyboardcommandsMenu_2  matlab.ui.container.Menu
    OpenonlinemanualMenu_2          matlab.ui.container.Menu
    VersionMenu                     matlab.ui.container.Menu
    CheckforanewversionMenu         matlab.ui.container.Menu
    LicenseMenu                     matlab.ui.container.Menu
    MessagesPanel                   matlab.ui.container.Panel
    TrackerscoreLabel               matlab.ui.control.Label
    DLTresidualLabel                matlab.ui.control.Label
    MessageLabel                    matlab.ui.control.TextArea
    PointandtrackingcontrolsPanel   matlab.ui.container.Panel
    LocationpredictorDropDown       matlab.ui.control.DropDown
    LocationpredictorLabel          matlab.ui.control.Label
    PointzoomviewLabel              matlab.ui.control.Label
    DLTresidualthresholdEditField   matlab.ui.control.NumericEditField
    DLTresidualthresholdEditFieldLabel  matlab.ui.control.Label
    SearchareawidthEditField        matlab.ui.control.NumericEditField
    SearchareawidthEditFieldLabel   matlab.ui.control.Label
    TrackerThresholdEditField       matlab.ui.control.NumericEditField
    TrackerThresholdEditFieldLabel  matlab.ui.control.Label
    MarkercentroidsearchDropDown    matlab.ui.control.DropDown
    MarkercentroidsearchDropDownLabel  matlab.ui.control.Label
    TrackerDropDown                 matlab.ui.control.DropDown
    TrackerDropDownLabel            matlab.ui.control.Label
    AutotrackmodeDropDown           matlab.ui.control.DropDown
    AutotrackmodeDropDownLabel      matlab.ui.control.Label
    AddapointButton                 matlab.ui.control.Button
    CurrentpointDropDown            matlab.ui.control.DropDown
    CurrentpointDropDownLabel       matlab.ui.control.Label
    SmallPlotAxes                   matlab.ui.control.UIAxes
    VideodisplaycontrolsPanel       matlab.ui.container.Panel
    BigstepsizeEditField            matlab.ui.control.NumericEditField
    BigstepsizeEditFieldLabel       matlab.ui.control.Label
    FrameadvancestepsizeEditField   matlab.ui.control.NumericEditField
    FrameadvancestepsizeEditFieldLabel  matlab.ui.control.Label
    Show2DtracksonvideoimagesButtonGroup  matlab.ui.container.ButtonGroup
    NumofframesEditField            matlab.ui.control.NumericEditField
    offramesEditFieldLabel          matlab.ui.control.Label
    SelectedpointButton             matlab.ui.control.RadioButton
    AllButton                       matlab.ui.control.RadioButton
    NoneButton                      matlab.ui.control.RadioButton
    ShowDLTvisualfeedbackCheckBox   matlab.ui.control.CheckBox
    UpdateallvideosCheckBox         matlab.ui.control.CheckBox
    FrameNumberFieldLabel           matlab.ui.control.Label
    FrameNumberField                matlab.ui.control.NumericEditField
    Label                           matlab.ui.control.Label
    FrameNumberSlider               matlab.ui.control.Slider
    FrameNumberSliderLabel          matlab.ui.control.Label
    VideoGammaSlider                matlab.ui.control.Slider
    VideoGammaLabel                 matlab.ui.control.Label
    SubtractbackgroundCheckBox      matlab.ui.control.CheckBox
    DisplayincolorCheckBox          matlab.ui.control.CheckBox
  end

    
    properties (Access = public)
        nvid double % number of videos
        handles cell % handles for different graphical elements
        dltpts double % 3D points sparse array
        dltres double % 3D residuals sparse array
        xypts double % 2D points sparse array
        pointNames cell % cell array of point string names
        offset double % movie offsets sparse array
        numpts double % number of points
        sp double % selected point
        recentlysaved logical % has the data been saved?
        reloadVid logical % force reloading of all videos
        gapJump double % number of "missing data" frames to try and skip over
        cdataCache cell % cache of recently viewed video frames
        cdataCacheIdx cell % index of cdataCache
        movFrameRates double % movie framerates
        movFrameRateRatios double % movie framerate ratios
        movsizes double % movie sizes
        camd cell % distortion transforms
        camud cell % undistortion transforms
        drawVid logical % whether to re-draw different videos
        dltcoef double % DLT coefficients
        dltFileName string % path and filename of DLT coefficients
        dlt = false % Whether or not DLT operations are enabled
        dlt2d = false % whether or not DLT mode is 2D
        lastvnum = 1; % Number of last active video
        lastaxh=301; % index to last video axis
        currframe double % current actual frame displayed in each video. Get current logical frame from the slider!
        initComplete = false; % has initialization completed?
        dataStores cell % Cell array of moviedatastores for dnn training
        deepLearningNetworks cell % Cell array of networks and associated info
        subAppObjects=cell(1,10); % Cell array of object handles from subApps
        saveName = ''; % filename to save as
        savePath = ''; % pathname to save as
    end
    
    properties (Access = private)
        bak % container for digitized point backups
        version = 8.5 % Version of the program
        versionString = '8.5.0'; % extended version information
        versionMajor = 8;
        versionMinor = 5;
        versionPoint = 0;
    end
    
    methods (Access = private)
        
        function [] = dvMakeMovieWindows(app,invisible)
            
            % function [] = dvMakeMovieWindows(app,invisible)
            %
            % Function for creating the video windows and handles
            
            % compatibility hack
            uda=[];
            
            if exist('invisible','var')~=2
                invisible=false;
            end
            if invisible
                vString='off';
            else
                vString='on';
            end
            
            % for typing convenience...
            h=app.handles;
            
            % create movie figures and axes
            for i=1:app.nvid
                h{200+i} = figure('units','pixels', 'color',[0.83 0.82 0.78],...
                    'doublebuffer','on','KeyPressFcn',{@dvKeyPress,app},'menubar','none',...
                    'Name',sprintf('Movie %.0f',i),'numbertitle','off',...
                    'handlevisibility','callback','tag',sprintf('movieFig%.0f',i),...
                    'Userdata',uda,'Visible',vString,'deletefcn',{@dvDeleteFcn,app},...
                    'pointer','cross','interruptible','off','busyaction','cancel','position',...
                    [361+i*10 242-i*10 560 420],'WindowScrollWheelFcn',{@dvScrollWheel,app},...
                    'WindowButtonMotionFcn',@(~,~)0);
                
                setappdata(h{200+i},'videoNumber',i);
                
                movegui(h{200+i}); % make sure the figure is onscreen
                
                h{300+i} = axes('Position',[.01 .10 .99 .89],'XTickLabel','', ...
                    'YTickLabel','','ButtonDownFcn',{@dvButtonDown,app}, ...
                    'Visible','off','ydir','reverse','Parent',h{200+i});
                
                uicontrol('Parent',h{200+i},... % frame offset label
                    'Units','characters','BackgroundColor',[0.83 0.82 0.78],...
                    'HorizontalAlignment','right',...
                    'Position',[12 1 20 2],'String','Frame offset:',...
                    'Style','text','Tag','text4');
                
                h{325+i} = uicontrol('Parent',h{200+i},... % frame offset box
                    'Units','characters','BackgroundColor',[1 1 1],'Position',...
                    [34 1.5 7 2],'String','0','Style','edit','Tag','edit2', ...
                    'Callback',{@dvVideoOffsetUpdate,app}','enable','on');
                
                h{400+i} = uicontrol('Parent',h{200+i},... % undistortion button
                    'Units','characters','BackgroundColor',[1 1 1],'Position',...
                    [57 1.5 26 2],'String','Set Undistortion profile','style','pushbutton',...
                    'Tag','UndistortionButton','Callback',{@dvSetUndistortion,app},'enable','on',...
                    'Visible','on');
                
                h{425+i} = uicontrol('Parent',h{200+i}, ... % undistortion file name
                    'Units','characters','BackgroundColor',[0.83 0.82 0.78],...
                    'HorizontalAlignment','left',...
                    'Position',[57 0 40 2],'String','Undistortion file: none',...
                    'Style','text','Tag','text4','visible','on');
                
                uicontrol('Parent',h{200+i},... % frame rate label
                    'Units','characters','BackgroundColor',[0.83 0.82 0.78],...
                    'HorizontalAlignment','right',...
                    'Position',[0 1 10 2],'String','Frame rate (Hz):',...
                    'Style','text','Tag','frameRateText');
                
                h{450+i} = uicontrol('Parent',h{200+i},... % frame rate edit box
                    'Units','characters','BackgroundColor',[1 1 1],'Position',...
                    [11 1.5 7 2],'String','0','Style','edit','Tag','frameRateEdit', ...
                    'Callback',{@dvFrameRateUpdate,app},'enable','on');
                
                %         if i==1
                %           set(h{325+i},'enable','off') % dont' allow updating the offset of video 1
                %         end
            end
            app.handles=h;
            
        end
        
        function [] = tsInitWindow_v1(app)
            % function [] = tsInitWindow_v1(app)
            %
            % Initialize the timeseries window
            
            
            % Create the timeseries window
            app.handles{600} = figure('Units','pixels',... % timeseries figure
                'Color',[0.831 0.815 0.784]','Doublebuffer','on', ...
                'IntegerHandle','off','MenuBar','none',...
                'Name','DLTdv8 timeseries','NumberTitle','off',...
                'Position',[20 20 800 400],'Resize','on',...
                'HandleVisibility','callback','Tag','ts_figure',...
                'Userdata',[],'Visible','on','deletefcn',{@dvDeleteFcn,app},...
                'interruptible','on','KeyPressFcn',{@tsKeyPress,app});
            
            % make sure the figure is onscreen
            movegui(app.handles{600});
            
            % timeseries axis
            app.handles{601} = axes('Position',[.1 .25 .89 .74], ...
                'ButtonDownFcn','foofun', ...
                'Visible','on','Parent',app.handles{600});
            
            % window width label
            uicontrol('Parent',app.handles{600},...
                'Units','characters','BackgroundColor',[0.83 0.82 0.78],...
                'HorizontalAlignment','right',...
                'Position',[2 1 20 2],'String','Window width (frames):',...
                'Style','text','Tag','wwText');
            
            % window width textbox
            app.handles{602} = uicontrol('Parent',app.handles{600},...
                'Units','characters','BackgroundColor',[1 1 1],'Position',...
                [24 1.5 9 2],'String',num2str(size(app.xypts,1)),'Style','edit',...
                'Tag','edit2','Callback',{@tsWWtext,app},'enable','on');
            
            % Display label
            uicontrol('Parent',app.handles{600},...
                'Units','characters','BackgroundColor',[0.83 0.82 0.78],...
                'HorizontalAlignment','right',...
                'Position',[37 1 8 2],'String','Display:',...
                'Style','text','Tag','wwDisplayText');
            
            % get the pulldown menu string
            s=tsWinMakePDstring(app);
            
            % pulldown menu for selecting what to display
            app.handles{603} = uicontrol(...
                'Parent',app.handles{600},'Units','characters','Position',...
                [46 1.5 11 2],'String',s,'Style','popupmenu',...
                'Value',1,'Tag','popupmenu2','Enable','on','Callback',...
                {@tsRedraw,app});
            
        end
        
        
        function [pseq] = getTSseq(app,fr,len)
            
            % function [s] = getTSseq(app,fr,len)
            %
            % Function to create the display point sequence from the timeseries window
            % width
            
            ww=str2double(get(app.handles{602},'string'));
            if fr<ww/2+1
                ls=round(max([1 fr-ww/2]));
                %rs=round(min([len, ls+ww/2.1]));
                %rs=round(min([len, ls+ww])); % preserve size of time-series window
                rs=round(min([len,ww+1]));
            else
                rs=round(min([len, fr+ww/2]));
                ls=round(max([1 rs-ww]));
            end
            pseq=ls:rs;
            
        end
        
        function [pseq] = getPSseq(app,fr)
            % function [pseq] = getPSseq(app,fr)
            %
            % Function to create the display point sequence for the
            % point-tracks on the video images
            ww=app.NumofframesEditField.Value;
            len=size(app.xypts,1);
            
            % set base sequence
            if mod(ww,2)~=0 % odd
                pseq=[fr-floor(ww/2):fr+floor(ww/2)];
            else % even
                pseq=[fr-floor(ww/2)+1:fr+floor(ww/2)];
            end
            
            % fix ends
            if pseq(1)<1 
                pseq=pseq-pseq(1)+1;
            end
            if pseq(end)>len
                pseq=pseq-(pseq(end)-len);
            end
            if pseq(1)<1
                pseq(pseq<1)=[];
            end
            
        end
        
        function enableUIElements(app)
            % Enable the appropriate parts of the UI during initialization
            app.FrameNumberSlider.Enable=true;
            app.SaveMenu.Enable=true;
            app.SaveasMenu.Enable=true;
            app.NewMenu.Enable=false;
            app.LoadMenu.Enable=false;
            app.FrameNumberField.Enable=true;
            app.DisplayincolorCheckBox.Enable=true;
            app.VideoGammaSlider.Enable=true;
            app.VideoGammaLabel.Enable=true;
            app.FrameNumberSliderLabel.Enable=true;
            app.SubtractbackgroundCheckBox.Enable=true;
            app.CurrentpointDropDown.Enable=true;
            app.AddapointButton.Enable=true;
            app.AutotrackmodeDropDown.Enable=true;
            app.TrackerDropDownLabel.Enable=true;
            app.TrackerDropDown.ItemsData=0:1;
            app.LocationpredictorDropDown.Enable=true;
            app.LocationpredictorLabel.Enable=true;
            app.LocationpredictorDropDown.ItemsData=1:3;
            app.LocationpredictorDropDown.Value=1;
            app.SearchareawidthEditField.Enable=true;
            app.TrackerThresholdEditField.Enable=true;
            app.CurrentpointDropDownLabel.Enable=true;
            app.FrameNumberFieldLabel.Enable=true;
            app.TrackerThresholdEditFieldLabel.Enable=true;
            app.SearchareawidthEditField.Enable=true;
            app.SearchareawidthEditFieldLabel.Enable=true;
            app.AutotrackmodeDropDownLabel.Enable=true;
            app.TrackerDropDownLabel.Enable=true;
            app.AllButton.Enable=true;
            app.SelectedpointButton.Enable=true;
            app.NumofframesEditField.Enable=true;
            app.offramesEditFieldLabel.Enable=true;
            app.NoneButton.Enable=true;
            app.PointsMenu.Enable=true;
            app.DMenu.Enable=true;
            app.MessageLabel.Enable=true;
            app.TrackerscoreLabel.Enable=true;
            app.FrameadvancestepsizeEditField.Enable=true;
            app.BigstepsizeEditField.Enable=true;
            app.FrameadvancestepsizeEditFieldLabel.Enable=true;
            app.BigstepsizeEditFieldLabel.Enable=true;
            app.ExporttoworkspaceMenu.Enable=true;
            app.LoadnewDLTcoefficientsMenu.Enable=true;
            app.ForcemovieseekingMenu.Enable=true;
            app.ClearmoviecacheMenu.Enable=true;
            
            % if more than 1 video, enable to video updating checkbox
            if app.nvid>1
                app.UpdateallvideosCheckBox.Enable=true;         
            end
            
            % if in DLT
            if app.dlt
                app.ShowDLTvisualfeedbackCheckBox.Enable=true;
                app.DLTresidualthresholdEditField.Enable=true;
                app.DLTresidualthresholdEditFieldLabel.Enable=true;
                app.DLTresidualLabel.Enable=true;
                app.LoadnewDLTcoefficientsMenu.Enable=true;
                app.Recompute3DpointsMenu.Enable=true;
                app.ExportDLTcoefficientsMenu.Enable=true;
            end
            
            % if Deep Learning tools are available
            try
                dnn=~verLessThan('nnet','13') & ...
                    ~verLessThan('stats','4') & ~verLessThan('images','1');
            catch ME
                dnn=false;
            end
            if dnn
                app.TrackerDropDown.Enable=true;
                app.DeepLearningMenu.Enable=true;
            end
            
            % turn on Centroid finding & set value to "On" if the image analysis
            % toolbox functions that it depends on are available
            if exist('imbinarize')~=0 && exist('regionprops')~=0
                app.MarkercentroidsearchDropDown.Enable=true;
                app.MarkercentroidsearchDropDownLabel.Enable=true;
                app.MessageLabel.Value={'Detected Image Analysis toolbox, centroid localization is', ...
                    'available, enable it via the menu in the Points panel.'};
            else
                app.MessageLabel.Value={'The Image Analysis toolbox is not available, centroid ', ...
                    'localization has been disabled.'};
            end
            
            % enable on focus events for windows (still works in r2019b, gone in 2025a)
            warning off MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame
            warning off MATLAB:ui:javaframe:PropertyToBeRemoved
            try
                for i=1:app.nvid
                    jFig = get(app.handles{200+i}, 'JavaFrame');
                    jAxis = jFig.getAxisComponent;
                    cc=jAxis.countComponents;
                    for j=1:cc
                        set(jAxis.getComponent(j-1),'FocusGainedCallback',{@figFocusFunc,app,app.handles{200+i}});
                    end
                end
            catch
                % protect this bit of non-crucial functionality from generating
                % errors in future MATLAB versions
            end

            
        end


    end
    
    methods (Access = public)
        function udExport=dvMakeSaveBundle(app)
        % function udExport=dvMakeSaveBundle(app)
        %
        % Gathers data and interface settings into a bundle for saving to
        % disk or export to the main MATLAB workspace

            udExport.DLTdv_version = app.version;
            
            % copy out the essentials from the actual arrays
            udExport.data.nvid = app.nvid;
            udExport.data.dltpts = app.dltpts;
            udExport.data.dltres = app.dltres;
            udExport.data.offset = app.offset;
            udExport.data.xypts = app.xypts;
            udExport.data.numpts = app.numpts;
            udExport.data.sp = app.sp;
            udExport.data.reloadVid = true; % set this for better re-initialization
            udExport.data.gapJump = app.gapJump;
            udExport.data.movsizes = app.movsizes;
            udExport.data.drawVid = app.drawVid;
            udExport.data.camd = app.camd;
            udExport.data.camud = app.camud;
            udExport.data.dltcoef = app.dltcoef;
            udExport.data.dltFileName=app.dltFileName;
            udExport.data.dlt = app.dlt;
            udExport.data.dlt2d = app.dlt2d;
            udExport.data.lastvnum = app.lastvnum;
            udExport.data.lastaxh = app.lastaxh;
            udExport.data.movFrameRates = app.movFrameRates;
            udExport.data.movFrameRateRatios = app.movFrameRateRatios;
            udExport.data.pointNames = app.pointNames;
            
            % copy deep learning properties
            udExport.dataStores=app.dataStores;
            for i=1:numel(udExport.dataStores)
                udExport.dataStores{i}.resetReaders;
            end
            udExport.deepLearningNetworks=app.deepLearningNetworks;
            
            % get properties for specific UI elements
            udExport.ui.gamma.props.value = app.VideoGammaSlider.Value;
            udExport.ui.color.props.value = app.DisplayincolorCheckBox.Value;
            udExport.ui.background.props.value = app.SubtractbackgroundCheckBox.Value;
            udExport.ui.frameText.props.value = app.FrameNumberField.Value;
            udExport.ui.frameSlider.props.value = app.FrameNumberSlider.Value;
            udExport.ui.frameSlider.props.Max = app.FrameNumberSlider.Limits(2);
            udExport.ui.frameSlider.props.Min = app.FrameNumberSlider.Limits(1);
            
            udExport.ui.autoMode.props.value = app.AutotrackmodeDropDown.Value;
            %udExport.ui.autoPredictor.props.value = get(uda.handles(35),'value');
            udExport.ui.autoThreshold.props.value = app.TrackerThresholdEditField.Value;
            udExport.ui.trackerType.props.value = app.TrackerDropDown.Value;
            udExport.ui.autoSize.props.value = app.SearchareawidthEditField.Value;
            udExport.ui.currentPoint.props.value = app.CurrentpointDropDown.Value;
            udExport.ui.currentPoint.props.items = app.CurrentpointDropDown.Items;
            
            udExport.ui.dltThreshold.props.value = app.DLTresidualthresholdEditField.Value;
            udExport.ui.dltFeedback.props.value = app.ShowDLTvisualfeedbackCheckBox.Value;
            
            udExport.ui.vidUpdate.props.value = app.UpdateallvideosCheckBox.Value;
            
            udExport.ui.show2Dtracks.props.value1 = app.SelectedpointButton.Value;
            udExport.ui.show2Dtracks.props.value2 = app.NoneButton.Value;
            udExport.ui.show2Dtracks.props.value3 = app.AllButton.Value;
            
            udExport.ui.findCentroid.props.value = app.MarkercentroidsearchDropDown.Value;
            
            udExport.ui.NumofframesEditField.props.value = app.NumofframesEditField.Value;
            
            udExport.ui.StepsizeEditField.props.value = app.FrameadvancestepsizeEditField.Value; % added 2022-03-07
            udExport.ui.BigstepsizeEditField.props.value = app.BigstepsizeEditField.Value; % added 2022-03-07

            udExport.ui.ForcemovieseekingMenu.Checked = app.ForcemovieseekingMenu.Checked; % added 2026-01-07
            
            udExport.tsui.tsWidth.props.string = get(app.handles{602},'string');
            udExport.tsui.tsWidth.handle = 602;
            
            udExport.tsui.tsMenu.props.string = get(app.handles{603},'string');
            udExport.tsui.tsMenu.props.value = get(app.handles{603},'value');
            udExport.tsui.tsMenu.handle = 603;
            
            % videos & video-specific info
            for i=1:app.nvid
                % video filename
                vname=getappdata(app.handles{300+i},'fname');
                if isstr(vname) % simple string
                    udExport.video.files{i}=vname;
                else
                  try
                    udExport.video.files{i}=[vname.Path,filesep,vname.Name];
                  catch
                    udExport.video.files{i}='video not specified';
                  end
                end
                
                % Video window UI elements
                udExport.video.offset{i}=get(app.handles{325+i},'string');
                udExport.video.mirror{i}=get(app.handles{350+i},'value');
                udExport.video.undistortString{i}=get(app.handles{425+i},'string');
                udExport.video.frameRateString{i}=get(app.handles{450+i},'string');
                
            end
            
            % secondary data stores
            udExport.currframe = app.currframe;

        end

        function dvSaveproject(app)
            % Save the whole DLTdv8 project as a mat-file
            
            % Get basic save bundle
            udExport=dvMakeSaveBundle(app);
            
            % write some info for the save version
            udExport.saveTime = datestr(now,'yyyy-mm-dd HH:MM:SS.FFF AM');

            % udExport.DLTdv_version = app.version;
            % 
            % % copy out the essentials from the actual arrays
            % udExport.data.nvid = app.nvid;
            % udExport.data.dltpts = app.dltpts;
            % udExport.data.dltres = app.dltres;
            % udExport.data.offset = app.offset;
            % udExport.data.xypts = app.xypts;
            % udExport.data.numpts = app.numpts;
            % udExport.data.sp = app.sp;
            % udExport.data.reloadVid = true; % set this for better re-initialization
            % udExport.data.gapJump = app.gapJump;
            % udExport.data.movsizes = app.movsizes;
            % udExport.data.drawVid = app.drawVid;
            % udExport.data.camd = app.camd;
            % udExport.data.camud = app.camud;
            % udExport.data.dltcoef = app.dltcoef;
            % udExport.data.dltFileName=app.dltFileName;
            % udExport.data.dlt = app.dlt;
            % udExport.data.lastvnum = app.lastvnum;
            % udExport.data.lastaxh = app.lastaxh;
            % udExport.data.movFrameRates = app.movFrameRates;
            % udExport.data.movFrameRateRatios = app.movFrameRateRatios;
            % 
            % % copy deep learning properties
            % udExport.dataStores=app.dataStores;
            % for i=1:numel(udExport.dataStores)
            %     udExport.dataStores{i}.resetReaders;
            % end
            % udExport.deepLearningNetworks=app.deepLearningNetworks;
            % 
            % % get properties for specific UI elements
            % udExport.ui.gamma.props.value = app.VideoGammaSlider.Value;
            % udExport.ui.color.props.value = app.DisplayincolorCheckBox.Value;
            % udExport.ui.background.props.value = app.SubtractbackgroundCheckBox.Value;
            % udExport.ui.frameText.props.value = app.FrameNumberField.Value;
            % udExport.ui.frameSlider.props.value = app.FrameNumberSlider.Value;
            % udExport.ui.frameSlider.props.Max = app.FrameNumberSlider.Limits(2);
            % udExport.ui.frameSlider.props.Min = app.FrameNumberSlider.Limits(1);
            % 
            % udExport.ui.autoMode.props.value = app.AutotrackmodeDropDown.Value;
            % %udExport.ui.autoPredictor.props.value = get(uda.handles(35),'value');
            % udExport.ui.autoThreshold.props.value = app.TrackerThresholdEditField.Value;
            % udExport.ui.trackerType.props.value = app.TrackerDropDown.Value;
            % udExport.ui.autoSize.props.value = app.SearchareawidthEditField.Value;
            % udExport.ui.currentPoint.props.value = app.CurrentpointDropDown.Value;
            % udExport.ui.currentPoint.props.items = app.CurrentpointDropDown.Items;
            % 
            % udExport.ui.dltThreshold.props.value = app.DLTresidualthresholdEditField.Value;
            % udExport.ui.dltFeedback.props.value = app.ShowDLTvisualfeedbackCheckBox.Value;
            % 
            % udExport.ui.vidUpdate.props.value = app.UpdateallvideosCheckBox.Value;
            % 
            % udExport.ui.show2Dtracks.props.value1 = app.SelectedpointButton.Value;
            % udExport.ui.show2Dtracks.props.value2 = app.NoneButton.Value;
            % udExport.ui.show2Dtracks.props.value3 = app.AllButton.Value;
            % 
            % udExport.ui.findCentroid.props.value = app.MarkercentroidsearchDropDown.Value;
            % 
            % udExport.ui.NumofframesEditField.props.value = app.NumofframesEditField.Value;
            % 
            % udExport.ui.StepsizeEditField.props.value = app.FrameadvancestepsizeEditField.Value; % added 2022-03-07
            % udExport.ui.BigstepsizeEditField.props.value = app.BigstepsizeEditField.Value; % added 2022-03-07
            % 
            % udExport.tsui.tsWidth.props.string = get(app.handles{602},'string');
            % udExport.tsui.tsWidth.handle = 602;
            % 
            % udExport.tsui.tsMenu.props.string = get(app.handles{603},'string');
            % udExport.tsui.tsMenu.props.value = get(app.handles{603},'value');
            % udExport.tsui.tsMenu.handle = 603;
            % 
            % % videos & video-specific info
            % for i=1:app.nvid
            %     % video filename
            %     vname=getappdata(app.handles{300+i},'fname');
            %     if isstr(vname) % simple string
            %         udExport.video.files{i}=vname;
            %     else
            %       try
            %         udExport.video.files{i}=[vname.Path,filesep,vname.Name];
            %       catch
            %         udExport.video.files{i}='video not specified';
            %       end
            %     end
            % 
            %     % Video window UI elements
            %     udExport.video.offset{i}=get(app.handles{325+i},'string');
            %     udExport.video.mirror{i}=get(app.handles{350+i},'value');
            %     udExport.video.undistortString{i}=get(app.handles{425+i},'string');
            %     udExport.video.frameRateString{i}=get(app.handles{450+i},'string');
            % 
            % end
            % 
            % % secondary data stores
            % udExport.currframe = app.currframe;
            
            % a last bit of info to go into the save bundle
            udExport.savePath=app.savePath;
            udExport.saveName=app.saveName;
            save([app.savePath,filesep,app.saveName],'udExport','-v7.3')
            
            % update saved status
            app.recentlysaved=true;
        end
        
        
        function fr = DLTautotrack3fun(app,h,keyadvance,axn,cp,fr,sp)
            % DLTautotrack3.m function
            %
            % This function handles the auto-tracking (both semi and auto mode)
            % functions for the DLTdv8.m
            %
            % Inputs:
            %   app = DLTdv8 app object
            %   h = handles structure
            %   keyadvance = whether or not the function was initiated by a key press
            %   axn = axis number of the video in question
            %   cp = [x,y] coordinates for the current point
            %   fr = # of the current frame
            %   sp = # of the selected point (was used in multi-point tracking)
            %
            % Version 1, Ty Hedrick, 8/1/04
            % Version 2, Ty Hedrick, 9/27/2005
            % Version 3, Ty Hedrick, 2018-08-26 [re-implement multi-track]
            
            % get/set basic variables
            frmax=app.FrameNumberSlider.Limits(2);
            psize=app.SearchareawidthEditField.Value;
            thold=app.TrackerThresholdEditField.Value;
            spReturn=sp; % original selected point
            % auto-track mode: 1=off, 2=advance, 3=semi, 4=auto, 5=multi
            atMode=find(startsWith(app.AutotrackmodeDropDown.Items,app.AutotrackmodeDropDown.Value));
            trackFailed=false; % tracking loop pass / fail marker
            stepSize=app.FrameadvancestepsizeEditField.Value; % step size
            
            % make sure we're not already at the end of the sequence
            if fr==frmax, return, end
            
            % set all axes to draw off
            app.drawVid(:)=false;
            
            % setup axn (axis number) matrix
            if atMode==5 && app.UpdateallvideosCheckBox.Value==true % multi-mode & all update videos
                AAxn=1:app.nvid;
                axnReturn=1; % assumed original axis
            else % any other mode
                AAxn=axn;
                app.drawVid(axn)=true;
                axnReturn=axn; % original axis
            end
            
            % grab the image caches
            oData.cdataCache=app.cdataCache;
            oData.cdataCacheIdx=app.cdataCacheIdx;
            
            % setup base matching image areas
            % loop through each axis
            for A=1:numel(AAxn)
                axn=AAxn(A); % set the working axis
                
                % extract image
                kids=get(h{axn+300},'Children'); % children of current axis
                imdat=get(kids(end),'CData'); % read current image
                
                % setup spM (selected point matrix) for this video axis
                if atMode==5 % multi-track mode
                    spM(1,axn)=0; % make sure spM gets some value
                    spInc=1; % selected point incrementer (loop counter)
                    for i=1:size(app.xypts,2)/(app.nvid*2) % for each point
                        % add to set if defined in this frame but not the next
                        if app.xypts(fr,axn*2-1+(i-1)*2*app.nvid)~=0 && ...
                                app.xypts(fr+1,axn*2-1+(i-1)*2*app.nvid)==0
                            spM(spInc,A)=i;
                            x=round(app.xypts(fr,axn*2-1+(i-1)*2*app.nvid));
                            y=round(app.xypts(fr,axn*2+(i-1)*2*app.nvid));
                            % grab for comparison to the next frame;
                            try
                                RB(:,:,:,spInc,A)=imdat(y-psize:y+psize,x-psize:x+psize,:);
                            catch
                                RB(:,:,:,spInc,A)=NaN;
                            end
                            spInc=spInc+1;
                        end
                    end
                else % non-multi-mode
                    spM=sp;
                    x=round(cp(1,1)); % get an integer X point
                    y=round(cp(1,2)); % get an integer Y point
                    % grab image region of interest for comparison to the next frame
                    try
                        RB=imdat(y-psize:y+psize,x-psize:x+psize,:);
                    catch
                        RB=NaN;
                    end
                end
                
            end
            
            % check that we found some points to track
            if isempty(find(spM>0,1))
                trackFailed=true;
            end
            
            % set videos frames to draw
            app.drawVid(AAxn(sum(spM,1)>0))=true;
            
            % set number of unsuccessful tracks in each axis and point
            failedFrames=zeros(size(spM));
            
            % end of initialization
            
            % main auto-tracking loop
            while fr + stepSize <= frmax && trackFailed==false && find(startsWith(app.AutotrackmodeDropDown.Items,app.AutotrackmodeDropDown.Value))~=1
                
                fr=fr+stepSize; % increment the frame number by stepSize
                
                % track frames that fail to track in this round
                localFailedFrames=failedFrames*0;
                
                % loop through each axis
                for A=1:numel(AAxn)
                    axn=AAxn(A); % set the working axis
                    
                    % setup video stream and tag
                    if axn>1
                        offset=str2double(get(h{axn+325},'String')); % video stream offset
                    else
                        offset=0;
                    end
                    fname=getappdata(h{axn+300},'fname'); % fname holds the movie filename
                    
                    % load and process the next frame
                    oData.movs{axn}=mediaRead2(fname,round(fr*app.movFrameRateRatios(axn)+offset),app.ForcemovieseekingMenu.Checked);
                    imdat=oData.movs{axn}.cdata(:,:,:);
                    oData.cdataCache{axn}=pushBuffer(oData.cdataCache{axn},imdat);
                    oData.cdataCacheIdx{axn}=pushBuffer(oData.cdataCacheIdx{axn},round(fr*app.movFrameRateRatios(axn)+offset));
                    
                    % for each point in the tracking set
                    for S=1:numel(find(spM(:,A)>0))
                        sp=spM(S,A); % selected point
                        
                        % extract roibase from the preloaded matrix
                        roibase=squeeze(RB(:,:,:,S,A)); % S = selected point, A = axis
                        
                        % call the prediction function - it will use the other digitized
                        % point locations to try and predict the location of the next point
                        otherpts=app.xypts(:,(axn*2-1:axn*2)+(sp-1)*2*app.nvid);
                        [x,y]=AutoTrackPredictor(otherpts,fr-stepSize,app.LocationpredictorDropDown.Value,stepSize); % 1 = Kalman, 2 = linear, 3 = static
                        
                        % constrain x & y to fit within imdat
                        if x>size(imdat,2) || x<1
                            x=round(otherpts(fr-1,1));
                        end
                        if y>size(imdat,1) || y<1
                            y=round(otherpts(fr-1,2));
                        end
                        
                        % search for a matching image in the next frame using the
                        % predicted x & y coordinates
                        if app.TrackerDropDown.Value==0 % cross-correlation tracker
                            gammaS=app.VideoGammaSlider.Value;
                            try
                                [xnew,ynew,fitt]=regionID4(app,double(roibase),[x,y], ...
                                    double(imdat),gammaS);
                            catch
                                fitt=-1;
                                xnew=NaN;
                                ynew=NaN;
                            end
                            cp=[xnew,ynew]; % update currentpoint
                            
                            % Search for a marker centroid (if desired & possible)
                            if isnan(cp(1))==0 && strcmp('none',app.MarkercentroidsearchDropDown.Value)==false
                                [cp]=click2centroid(app,cp,axn,imdat);
                            end
                        else
                            % Deep Neural Network based tracking
                            netNumber=app.TrackerDropDown.Value;
                            
                            % cropping block size
                            blockSize=112;
                            
                            % get crop region
                            rx=[-blockSize blockSize-1]+round(x(1)); % basic
                            % check borders
                            if rx(1)<1
                                rx=rx-rx(1)+1;
                            end
                            if rx(2)>size(imdat,2)
                                rx=rx-rx(2)+size(imdat,2);
                            end
                            ry=[-blockSize blockSize-1]+round(y(1)); % basic
                            if ry(1)<1
                                ry=ry-ry(1)+1;
                            end
                            if ry(2)>size(imdat,1)
                                ry=ry-ry(2)+size(imdat,1);
                            end
                            
                            % pull the subregion
                            img2=imdat(ry(1):ry(2),rx(1):rx(2),:);
                            if size(img2,3)==1
                                img2=repmat(img2,[1 1 3]);
                            end
                            
                            % get a network prediction & find the best match
                            imOut=predict(app.deepLearningNetworks{netNumber}{1},single(img2)/255);
                            
                            %code that I added
                            x_end = size(imOut,2);
                            y_end = size(imOut,1);
                            x_center = round(size(imOut,2)/2);
                            y_center = round(size(imOut,1)/2);
                            crop_size = 15;
                            %zero out all the values not in the cropped
                            %image to preserve scale 
                            imOut([1:y_center-crop_size, y_center+crop_size:y_end], [1:x_center-crop_size, x_center+crop_size:x_end], :) = 0;
                            %end code that I added

                            [m,n]=find(imOut(:,:,1)==max(max(imOut(:,:,1))));
                            cp=[rx(1)+n(1),ry(1)+m(1)];
                            fitt=max(max(imOut(:,:,1)))*20; % multiply by 20 to approximate the cross-correlation fit scale
                        end
                        
                        % update app.xypts
                        app.xypts(fr,axn*2-1+(sp-1)*2*app.nvid)=cp(1,1); % set x point
                        app.xypts(fr,axn*2+(sp-1)*2*app.nvid)=cp(1,2); % set y point
                        app.offset(fr,axn)=offset; % set offset
                        intData.fitt(sp,axn)=fitt; % keep fitt for later decision-making
                        
                        % post-matching decision making
                        if fitt < thold || isnan(fitt)
                            failedFrames(S,A)=failedFrames(S,A)+1;
                            localFailedFrames(S,A)=1;
                            app.drawVid(:)=true;
                            app.xypts(fr,axn*2-1+(sp-1)*2*app.nvid)=0; % set x point
                            app.xypts(fr,axn*2+(sp-1)*2*app.nvid)=0; % set y point
                            spReturn=sp; % set return point to the one that failed
                            axnReturn=axn; % set return axis
                        else
                            % Shift the image display area to follow the point if we've
                            % tracked it out of the screen
                            
                            % very slow method
                            % axisdata=get(app.handles{axn+300});
                            
                            % fast(er) method, added 2021-06-08
                            axisdata.XLim=xlim(app.handles{axn+300});
                            axisdata.YLim=ylim(app.handles{axn+300});
                            
                            % X axis
                            if cp(1)>axisdata.XLim(2)     % right of current xlim
                                delta=cp(1)-axisdata.XLim(2)+10;
                                axisdata.XLim(2)=axisdata.XLim(2)+delta;
                                axisdata.XLim(1)=axisdata.XLim(1)+delta;
                                set(app.handles{axn+300},'XLim',axisdata.XLim);
                            elseif cp(1)<axisdata.XLim(1) % left of current xlim
                                delta=cp(1)-axisdata.XLim(1)-10;
                                axisdata.XLim(2)=axisdata.XLim(2)+delta;
                                axisdata.XLim(1)=axisdata.XLim(1)+delta;
                                set(app.handles{axn+300},'XLim',axisdata.XLim);
                            end
                            % Y axis
                            if cp(2)>axisdata.YLim(2)     % above of current ylim
                                delta=cp(2)-axisdata.YLim(2)+10;
                                axisdata.YLim(2)=axisdata.YLim(2)+delta;
                                axisdata.YLim(1)=axisdata.YLim(1)+delta;
                                set(app.handles{axn+300},'YLim',axisdata.YLim);
                            elseif cp(2)<axisdata.YLim(1) % below of current ylim
                                delta=cp(2)-axisdata.YLim(1)-10;
                                axisdata.YLim(1)=axisdata.YLim(1)+delta;
                                axisdata.YLim(2)=axisdata.YLim(2)+delta;
                                set(app.handles{axn+300},'YLim',axisdata.YLim);
                            end
                        end % end of "if fitt" block
                        
                    end % end of points loop
                end % end of axes loop
                
                % strip any zeros from the intData.fitt matrix
                intData.fitt(intData.fitt==0)=NaN;
                
                % DLT update
                dltSet=unique(spM);
                dltSet(dltSet<1)=[];
                for sp=dltSet'
                    if sum(app.xypts(fr,(1:app.nvid*2)+(sp-1)*2*app.nvid)~=0)>=4 && ~isempty(app.dltcoef) || app.dlt2d==true % 2+ xypts & DLT coefficients OR 2D mode
                        
                        % subframe interpolation of 2D points
                        try
                            xy=sp2full(app.xypts(fr-1:fr+1,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                            xyI=xy;
                            sfi=mod(full(app.offset(fr,:)),1); % subframe interpolation
                            skel=(-1:1)'; % interpolation sequence
                            for i=1:app.nvid % loop through each camera
                                ndx=find(isfinite(xy(:,i*2)));
                                if numel(ndx)>1
                                    xyI(ndx,i*2-1:i*2)=interp1(skel(ndx),xy(ndx,i*2-1:i*2),skel(ndx)+sfi(i),'linear','extrap');
                                end
                            end
                            udist=xyI(2,:);
                        catch % fallback to no interpolation
                            udist=sp2full(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                        end
                        
                        % old version with no subframe interpolation
                        %udist=sp2full(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                        
                        for i=1:numel(udist)./2
                            if isempty(app.camud{i})==false
                                udist(1,i*2-1:i*2)=applyTform(app.camud{i},udist(1,i*2-1:i*2));
                            end
                        end
                        [xyz,res]=dlt_reconstruct_v2(app.dltcoef,udist); % do DLT
                        app.dltpts(fr,sp*3-2:sp*3)=full2sp(xyz(1:3)); % get the DLT points
                        app.dltres(fr,sp)=full2sp(res); % get the DLT residual
                        
                        % check that we didn't exceed the DLT residual threshold
                        if res > app.DLTresidualthresholdEditField.Value
                            failedFrames(S,A)=failedFrames(S,A)+1;
                            localFailedFrames(S,A)=1;
                            app.drawVid(:)=true;
                            spReturn=sp; % set return point to the failed point
                            sdx=find(intData.fitt(sp,:)==min(intData.fitt(sp,:)));
                            axnReturn=sdx; % set return axis
                            app.xypts(fr,[sdx*2-1,sdx*2]+(sp-1)*2*app.nvid)=0; % set xy points to 0
                            
                            % subframe interpolation of 2D points
                            try
                                xy=sp2full(app.xypts(fr-1:fr+1,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                                xyI=xy;
                                sfi=mod(full(app.offset(fr,:)),1); % subframe interpolation
                                skel=(-1:1)'; % interpolation sequence
                                for i=1:app.nvid % loop through each camera
                                    ndx=find(isfinite(xy(:,i*2)));
                                    if numel(ndx)>1
                                        xyI(ndx,i*2-1:i*2)=interp1(skel(ndx),xy(ndx,i*2-1:i*2),skel(ndx)+sfi(i),'linear','extrap');
                                    end
                                end
                                udist=xyI(2,:);
                            catch % fallback to no interpolation
                                udist=sp2full(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                            end
                            
                            % old line with no subframe interpolation
                            % udist=sp2full(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid));
                            for i=1:numel(udist)./2
                                if isempty(app.camud{i})==false
                                    udist(1,i*2-1:i*2)=applyTform(app.camud{i},udist(1,i*2-1:i*2));
                                end
                            end
                            [xyz,res]=dlt_reconstruct_v2(app.dltcoef,udist); %new DLT
                            app.dltpts(fr,sp*3-2:sp*3)=full2sp(xyz(1:3)); % get the DLT points
                            app.dltres(fr,sp)=full2sp(res); % get the DLT residual
                        end
                        
                    else
                        app.dltpts(fr,sp*3-2:sp*3)=0; % set DLT points to 0
                        app.dltres(fr,sp)=0; % set DLT residuals to 0
                    end
                end
                
                % change current point if necessary
                app.CurrentpointDropDown.Value=spReturn;
                
                % set auto-tracker fit text; use try-catch in case
                % intData.fitt is not defined for spReturn,axnReturn
                try
                    app.TrackerscoreLabel.Text=['Tracker score: ',num2str(intData.fitt(spReturn,axnReturn))];
                catch
                    app.TrackerscoreLabel.Text=['Tracker score: ',num2str(NaN)];
                end
                
                % advance a frame and redraw the screen
                app.FrameNumberSlider.Value=fr;
                fullRedraw(app,oData);
                
                if atMode==3 || (atMode==5 && keyadvance==1)
                    if keyadvance==0 %click advance in semiauto mode
                        quickRedraw(app,h,sp,fr);
                        updateSmallPlot(app,h,axn,cp);
                    else % advance via 'f' key
                        % do nothing
                    end
                    return % semi-auto mode returns after one pass
                else
                    % do nothingr
                end
                
                % check failure states
                if max(max(failedFrames))>app.gapJump
                    trackFailed=true;
                end
                
                % reset failedFrames to zero in cases where we acquired data
                failedFrames=failedFrames.*localFailedFrames;
                
                drawnow
            end % end of main auto-track "while" loop
            
        end
        
        function [] = fullRedraw(app,oData)
            
            % function [] = fullRedraw(app,oData)
            %
            % Full frame redraw, i.e. plot new video frames in DLTdv
            
            % gather information & data
            h=app.handles;
            sp=app.sp; % the selected point
            if exist('oData','var')==true
                cdataCache=oData.cdataCache;
                cdataCacheIdx=oData.cdataCacheIdx;
                %disp('got image cache data from autotrack loop')
            else
                cdataCache=app.cdataCache;
                cdataCacheIdx=app.cdataCacheIdx;
            end
            
            % get background subtraction value
            bsubVal=app.SubtractbackgroundCheckBox.Value;
            
            % get color mode info
            colorVal=app.DisplayincolorCheckBox.Value;
            
            % generate a new, gamma-scaled colormap if in grayscale mode
            if colorVal==0 || bsubVal==true % gray
                c=repmat((0:1/255:1)',1,3); % gray(256) colormap
                cnew=c.^app.VideoGammaSlider.Value;
            end
            
            if app.initComplete==true % if initialized
                fr=round(app.FrameNumberSlider.Value); % get the frame # from the slider
                app.FrameNumberSlider.Value=fr; % set the rounded value back
                frmax=app.FrameNumberSlider.Limits(2); % get the slider max
                
                % get list of videos to update
                if app.UpdateallvideosCheckBox.Value==true
                    vidlist=1:app.nvid; % all
                else
                    vidlist=app.lastvnum; % current
                    %disp(['Updating video ',num2str(app.lastvnum)])
                end
                
                % loop through each axes and update it if appropriate
                for i=vidlist
                    %fprintf('\nprocessing video %.0f\n',i)
                    % check to see if we should update this axis
                    if app.drawVid(i)==true || app.UpdateallvideosCheckBox.Value==true
                        fname=getappdata(h{i+300},'fname');
                        xl=xlim(h{i+300});
                        yl=ylim(h{i+300});
                        
                        % read the offset if not the first camera
                        offsetF=str2double(get(h{325+i},'String'));
                        
                        % write the offset
                        app.offset(fr,i)=offsetF;
                        
                        % round the offset for use in reading video frames
                        offset=round(offsetF);
                        %fprintf('offset is %.0f\n',offset)
                        
                        % read the video frame if possible and/or necessary
                        % check the cache
                        mov=[];
                        idx=find(cdataCacheIdx{i}==round(fr*app.movFrameRateRatios(i)+offset));
                        if numel(idx)>0 & app.reloadVid==false
                            mov.cdata=cdataCache{i}{idx(1)};
                            %disp('image in cache')
                        elseif round(fr*app.movFrameRateRatios(i)+offset) <= 0 || round(fr*app.movFrameRateRatios(i)+offset)>frmax*app.movFrameRateRatios(i)
                            % no image available
                            mov.cdata=chex(1,round(xl(2)),round(yl(2)));
                            cdataCacheIdx{i}=pushBuffer(cdataCacheIdx{i},round(fr*app.movFrameRateRatios(i)+offset));
                            cdataCache{i}=pushBuffer(cdataCache{i},mov.cdata);
                            
                            %disp('image not available')
                            %elseif round(fr*app.movFrameRateRatios(i)+offset) ~= round(app.currframe(i)*app.movFrameRateRatios(i)+offset) || app.reloadVid==true
                        %elseif round(fr*app.movFrameRateRatios(i)+offset) ~= app.currframe(i) || app.reloadVid==true
                        else
                            % need new image from file (or oData if autotracking)
                            %disp('getting new image')
                            try
                                if exist('oData','var')
                                    try
                                        mov=oData.movs{i}; % may not be fully populated
                                    catch
                                        mov=[];
                                    end
                                    if isempty(mov)
                                        mov=mediaRead2(fname,round(fr*app.movFrameRateRatios(i)+offset),app.ForcemovieseekingMenu.Checked);
                                    end
                                else
                                    mov=mediaRead2(fname,round(fr*app.movFrameRateRatios(i)+offset),app.ForcemovieseekingMenu.Checked);
                                end
                            catch
                                disp('Caught error displaying video! Error is:')
                                disp(lasterr)
                                mov.cdata=chex(1,round(xl(2)),round(yl(2)));
                            end
                            cdataCacheIdx{i}=pushBuffer(cdataCacheIdx{i},round(fr*app.movFrameRateRatios(i)+offset));
                            cdataCache{i}=pushBuffer(cdataCache{i},mov.cdata);
                            
                            
                        %else
                            % image already in memory
                        %    disp('image already displayed?')
                        end
                        
                        % check for background subtraction
                        bsubFrame = -3; % frame step for background subtraction
                        if app.SubtractbackgroundCheckBox.Value==true
                            
                            fOff=cdataCacheIdx{i}-round(fr*app.movFrameRateRatios(i)+offset);
                            idx=find(fOff<=-3 & fOff>=-6);
                            if numel(idx)>0
                                idx=flipud(sortrows([fOff(idx),idx]));
                                idx=idx(1,2);
                                mov.cdata=double(mov.cdata)-double(cdataCache{i}{idx(1)});
                                
                                if size(mov.cdata,3)>1
                                    mov.cdata=sum(mov.cdata,3);
                                end
                            elseif fr+offset+bsubFrame <= 0 || fr+offset+bsubFrame>frmax
                                % no image available, do nothing
                                app.MessageLabel.Value={'Background frame not available'};
                            else
                                % image potentially available, still do nothing since any other
                                % option is very sluggish with compressed video
                                app.MessageLabel.Value={'Background frame not available in cache'};
                            end
                        end
                        
                        % plot the frame parameters
                        hold(h{300+i},'off')
                        if colorVal==0 || (bsubVal==true && size(mov.cdata,3)==1) %gray
                            redlakeplot(app,mov.cdata(:,:,1),h{i+300});
                            colormap(h{i+300},cnew);
                        elseif colorVal==1 && size(mov.cdata,3)==1 % color w/ gray video
                            img=double(mov.cdata);
                            img=(img./max(max(img))).^app.VideoGammaSlider.Value;
                            redlakeplot(app,repmat(img,[1,1,3]),h{i+300});
                        else % color display with color video
                            % do the gamma scaling - since truecolor images don't have a
                            % colormap, it is not possible to do quick nonlinear transforms
                            % on the colormap instead of the image data itself.  Therefore,
                            % we're forced to just do linear scaling - i.e. change the
                            % image brightness
                            cdata=mov.cdata+(1-app.VideoGammaSlider.Value)*128;
                            
                            redlakeplot(app,cdata,h{i+300});
                        end
                        
                        % currframe is the actual frame from each stream that is being
                        % displayed
                        app.currframe(i)=round(fr*app.movFrameRateRatios(i)+offset);
                        set((h{i+300}),'XTickLabel','');
                        set((h{i+300}),'YTickLabel','');
                        set(h{i+300},'ButtonDownFcn',{@dvButtonDown,app});
                        xlim(h{i+300},xl); ylim(h{i+300},yl); % restore axis zoom
                        hold(h{300+i},'on')
                    end
                    
                end % end for loop through the video axes
                
                % call quickRedraw to add the non-image graphics
                quickRedraw(app,h,sp,fr);
                
                % set the force redraw switch to false
                app.reloadVid=false;
                
                % write back any modifications to the main figure Userdata
                app.cdataCache=cdataCache;
                app.cdataCacheIdx=cdataCacheIdx;
                
            end
            
        end
        
        function [] = updateTextFields(app)
            
            
            % set the frame # string
            fr=round(app.FrameNumberSlider.Value); % get current frame & max from the slider
            frmax=app.FrameNumberSlider.Limits(2);
            app.FrameNumberFieldLabel.Text=['/' num2str(frmax)];
            
            % set the frame # edit box value
            app.FrameNumberField.Value=fr;
            
            % set the DLT residual string
            if app.dlt
                app.DLTresidualLabel.Text=['DLT residual: ' num2str(sp2full(app.dltres(fr,app.sp)))];
            end
            
        end
        
        function quickRedraw(app,h,sp,fr)
            
            % function quickRedraw(app,h,sp,fr)
            %
            % Draws graphical (non-image) elements on the video panels - much quicker
            % than refreshing the image and graphical elements together.
            
            % set app.sp & app.currframe
            app.sp=sp;
            
            ptarray=[]; % init array of points to plot
            
            % get list of videos to update
            if app.UpdateallvideosCheckBox.Value==true % all
                vidlist=1:app.nvid;
            else
                vidlist=app.lastvnum; % current
            end
            
            % loop through each axes and update it
            for i=vidlist %1:uda.nvid % using vidlist only updates the active video(s)
                
                % get current x limits
                xl=xlim(h{i+300});
                
                % get the kids & delete everything but the image
                kids=get(h{i+300},'Children');
                if length(kids)>1
                    delete(kids(1:end-1)); % assume the bottom of the stack is the image
                end
                
                % plot any XY points (selected point in this frame)
                if app.xypts(fr,i*2+(sp-1)*2*app.nvid)~=0
                    plot(h{i+300},app.xypts(fr,(i)*2-1+(sp-1)*2*app.nvid), ...
                        app.xypts(fr,i*2+(sp-1)*2*app.nvid),'ro', ...
                        'HitTest','off','markersize',14); % ,'linewidth',2 removed for r2017b bugs
                end
                
                % get the user settings for plotting the extended DLT information
                dltvf=app.ShowDLTvisualfeedbackCheckBox.Value;
                
                % plot any DLT points (selected point) if desired
                if app.dltpts(fr,sp*3-2)~=0 && dltvf==true && app.dlt2d==false
                    xy=dlt_inverse(app.dltcoef(:,i),app.dltpts(fr,sp*3-2:sp*3));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %added dedistortion (Baier 1/16/06) (modified Hedrick 6/23/08)
                    % distort the xy points for the camera
                    if isempty(app.camd{i})==false
                        xy=applyTform(app.camd{i},xy);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    plot(h{i+300},xy(1),xy(2),'gd','HitTest','off','markersize',14,'linewidth',2);
                end
                
                % if no XY points & no DLT points plot partialDLT line if possible
                % & desired
                if app.xypts(fr,i*2+(sp-1)*2*app.nvid)==0 && app.dltpts(fr,sp*3-2)==0 ...
                        && sum(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid)~=0)==2 && ...
                        app.dlt==1 && dltvf==1 && app.dlt2d==false
                    
                    idx=find(app.xypts(fr,(1:2*app.nvid)+(sp-1)*2*app.nvid)~=0);
                    dltcoef1=app.dltcoef(:,idx(2)/2);
                    dltcoef2=app.dltcoef(:,i);
                    u=sp2full(app.xypts(fr,idx(1)+(sp-1)*2*app.nvid));
                    v=sp2full(app.xypts(fr,idx(2)+(sp-1)*2*app.nvid));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %added dedistortion (Baier 1/16/06) (modified Hedrick 6/23/08)
                    if isempty(app.camud{idx(2)/2})==false
                        uv=applyTform(app.camud{idx(2)/2},[u,v]);
                        u=uv(1); v=uv(2);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    [m,b]=partialdlt(u,v,dltcoef1,dltcoef2);
                    %xpts=xl'; % x-extent of video view
                    xpts=[-20;app.movsizes(i,2)+20];
                    ypts=m.*xpts+b;
                    %ypts=[-20;app.movsizes(i,1)+20];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %added dedistortion (Baier 1/16/06) (modified Hedrick 6/23/08)
                    % fixed for high-distortion cases on 2020-06-15
                    if isempty(app.camd{i})==false
                        yptsN=(ypts(1):(ypts(2)-ypts(1))/50:ypts(2))';
                        xptsN=(xpts(1):(xpts(2)-xpts(1))/50:xpts(2))';
                        
                        % generate y-based line points as well
                        %ypts=ylim(app.handles{i+300});
                        ypts=[-20;app.movsizes(i,1)+20];
                        xpts=(ypts-b)./m;
                        yptsN2=(ypts(1):(ypts(2)-ypts(1))/50:ypts(2))';
                        xptsN2=(xpts(1):(xpts(2)-xpts(1))/50:xpts(2))';
                        xyptsN=[[xptsN,yptsN];[xptsN2,yptsN2]];
                        
                        camD=app.camd{i};
                        
                        if isa(camD,'images.geotrans.LocalWeightedMeanTransformation2D')
                            % This form of the LWM transformation is nicer and produces output
                            % that's easier to clean up; no work to be done now
                        elseif isfield(camD.tdata,'ControlPoints')==true
                            D=zeros(size(xyptsN,1),1);
                            for j=1:size(xyptsN,1)
                                d=rnorm(camD.tdata.ControlPoints-repmat(xyptsN(j,:),...
                                    size(camD.tdata.ControlPoints,1),1));
                                D(j)=min(d);
                            end
                            xyptsN(D>50,:)=[];
                        end
                        xyptsN=sortrows(xyptsN,1);
                        
                        % distort the line of zero error
                        xyptsN=applyTform(camD,xyptsN);
                        
                        % clean geotrans LWM output here
                        if isa(camD,'images.geotrans.LocalWeightedMeanTransformation2D')
                            xyptsN(abs(xyptsN(:,1))>1e10,:)=[];
                        end
                        
                        xpts=xyptsN(:,1);
                        ypts=xyptsN(:,2);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    htmp=plot(h{i+300},xpts,ypts,'b-','PickableParts','none');
                    
                end % plot DLT points or epipolar
                
                % plot non-selected points
                if app.numpts>0 && (app.AllButton.Value || app.SelectedpointButton.Value)
                    %get display width for non-selected birds from the timeseries window
                    %pseq=getTSseq(app,fr,size(app.xypts,1));
                    pseq=getPSseq(app,fr);
                    if app.AllButton.Value
                        ptarray=1:app.numpts; % array of all the points
                        ptarray(sp)=[]; % remove the selected point
                        carray=repmat((i*2-1:i*2),numel(ptarray),1)+repmat(((ptarray-1)*2*app.nvid)',1,2);
                        carray=sort(reshape(carray,numel(carray),1))'; % list of columns to gather
                        % XY
                        xyPts=sp2full(app.xypts(fr,carray));
                        xyPts=reshape(xyPts,2,numel(xyPts)/2)';
                        plot(h{i+300},xyPts(isnan(xyPts(:,1))==false,1), ...
                            xyPts(isnan(xyPts(:,1))==false,2),'co','HitTest','off');
                        
                        %plot tracks for all non selected birds - for performance reasons these
                        %are plotted as a single line, doing anything else becomes very slow
                        %for large numbers of points (i.e. > 100).  This does complicate
                        %figuring out which point was clicked on when the line is selected by
                        %the user, to facilitate this the line IDs are stored in the linegroup
                        %userdata
                        
                        if numel(ptarray)>0
                            bxy=sp2full(app.xypts(pseq,carray));
                            bxy(end+1,:)=NaN;
                            ptarray2=repmat(ptarray,size(bxy,1),1);
                            ptarray2=reshape(ptarray2,numel(ptarray2),1);
                            bxyr=[reshape(bxy(:,1:2:end),numel(bxy)/2,1),reshape(bxy(:,2:2:end),numel(bxy)/2,1),ptarray2];
                            lgh=plot(h{i+300},bxyr(:,1),bxyr(:,2),'y-o','markersize',4,'HitTest',...
                                'on','ButtonDownFcn',{@selectTrackByClick2,h,app,fr,i});
                            set(lgh,'Userdata',bxyr(:,3));
                            
                            % plot tracks for all non selected birds, use a different color for the
                            % part close to the current frame
                            idx=max([pseq(1),fr-4]):min([pseq(end),fr+4]);
                            idx=idx(idx>0 & idx<=size(app.xypts,1));
                            
                            bxy=sp2full(app.xypts(idx,carray));
                            bxy(end+1,:)=NaN;
                            ptarray2=repmat(ptarray,size(bxy,1),1);
                            ptarray2=reshape(ptarray2,numel(ptarray2),1);
                            bxyr=[reshape(bxy(:,1:2:end),numel(bxy)/2,1),reshape(bxy(:,2:2:end),numel(bxy)/2,1),ptarray2];
                            lgh=plot(h{i+300},bxyr(:,1),bxyr(:,2),'c-o','markersize',4,'HitTest',...
                                'on','ButtonDownFcn',{@selectTrackByClick2,h,app,fr,i});
                            set(lgh,'Userdata',bxyr(:,3));
                            %disp('done close-in xy all')
                            %pause(1)
                        end
                    end
                    
                    % plot track of current point (modified on 2017-03-23)
                    if app.AllButton.Value || app.SelectedpointButton.Value
                        plot(h{i+300},sp2full(app.xypts(pseq,(i)*2-1+(sp-1)*2*app.nvid)),...
                            sp2full(app.xypts(pseq,i*2+(sp-1)*2*app.nvid)),'m-o','markersize',4,...
                            'HitTest','off')
                    end
                    
                    % XYZ
                    if app.dlt && isempty(ptarray)==false && app.ShowDLTvisualfeedbackCheckBox.Value
                        carray=repmat((1:3),numel(ptarray),1)+repmat(((ptarray-1)*3)',1,3);
                        carray=sort(reshape(carray,numel(carray),1))'; % list of columns to gather
                        
                        xyzPts=sp2full(app.dltpts(fr,carray));
                        xyzPts=reshape(xyzPts',3,numel(xyzPts)/3)';
                        xyPts=dlt_inverse(app.dltcoef(:,i),xyzPts(isnan(xyzPts(:,1))==false,:));
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %added dedistortion (Baier 1/16/06) (modified Hedrick 6/23/08)
                        if isempty(app.camd{i})==false
                            xyPts=applyTform(app.camd{i},xyPts);
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        plot(h{i+300},xyPts(:,1),xyPts(:,2),'cd','HitTest','off');
                    end
                end
                
            end % end for loop through the video axes
            
            % update the timeseries window
            % figure out what to plot
            pseq=getTSseq(app,fr,size(app.xypts,1)); % active point
            idx=get(h{603},'value'); % numerical index of the menu selection
            ptarray=1:app.numpts; % array of all the points
            ptarray(sp)=[]; % remove the selected point
            delete(get(h{601},'children')); % remove old lines
            
            if app.dlt && idx<=3
              % xyz data
              % non-selected points; note the cleverness for packing them
              % all in a single array for plotting quickly
              xyzPts=sp2full(app.dltpts(pseq,ptarray*3-3+idx));
              xyzPts(end+1,:)=NaN;
              xyzPts=reshape(xyzPts,1,numel(xyzPts));
              frPts=repmat([pseq';NaN],numel(ptarray),1);
              plot(h{601},frPts,xyzPts,'-o','HitTest','on','ButtonDownFcn',{@tsSelectTrackByClick,app},'markersize',1);
              hold(h{601},'on')

              % the selected point
              plot(h{601},pseq,sp2full(app.dltpts(pseq,sp*3-3+idx)),'r-d','markersize',1)
              doXY=false;
            elseif app.dlt && idx==4
              % residuals
              % non-selected points
              resPts=sp2full(app.dltres(pseq,ptarray));
              resPts(end+1,:)=NaN;
              resPts=reshape(resPts,1,numel(resPts));
              frPts=repmat([pseq';NaN],numel(ptarray),1);
              plot(h{601},frPts,resPts,'-o','HitTest','on','ButtonDownFcn',{@tsSelectTrackByClick,app},'markersize',1);

              % selected point
              hold(h{601},'on')
              plot(h{601},pseq,sp2full(app.dltres(pseq,sp)),'r-d','markersize',1)
              doXY=false;

            elseif app.dlt==true % and idx>4 (implicitly from above conditions)
                doXY=true;
                idx=idx-4;
            else
                doXY=true;
            end
            
            if doXY
                xyPts=sp2full(app.xypts(pseq,(ptarray-1)*2*app.nvid+idx));
                xyPts(end+1,:)=NaN;
                xyPts=reshape(xyPts,1,numel(xyPts));
                frPts=repmat([pseq';NaN],numel(ptarray),1);
                plot(h{601},frPts,xyPts,'-o','HitTest','on','ButtonDownFcn',{@tsSelectTrackByClick,app},'markersize',1);
                hold(h{601},'on')
                plot(h{601},pseq,sp2full(app.xypts(pseq,(sp-1)*2*app.nvid+idx)),'r-d','markersize',1)
            end
            
            set(h{601},'xlim',[pseq(1) pseq(end)+0.001])
            yl=ylim(h{601});
            plot(h{601},[fr;fr],yl','g--')
            hold(h{601},'off')
            
            % update the string fields in the GUI
            updateTextFields(app);

            % update the point meta editor if its open
            if isempty(app.subAppObjects{4})==false
              pe=app.subAppObjects{4};
              pe.drawPointTable();
              pe.updateButtons();
            end
            
        end
        
        function [h] = redlakeplot(app,img,ax)
            
            % function [h] = redlakeplot(app,image,ax)
            %
            % Description:	Quick function to plot images in an axis
            %
            % Version history:
            % 1.0 - Ty Hedrick 3/5/2002 - initial version
            
            
            h=image(img,'CDataMapping','scaled','parent',ax,'PickableParts','none');
            ax.YDir='reverse';
            axis normal
            
            colormap(ax,gray(256))
            hold(ax,'on')
            ha=get(h,'Parent');
            set(ha,'XTick',[],'YTick',[],'XColor',[0.8314 0.8157 0.7843],'YColor', ...
                [0.8314 0.8157 0.7843],'Color',[0.8314 0.8157 0.7843]);
            
        end
        
        function updateSmallPlot(app,h,vnum,cp,roi)
            
            % update the magnified plot in the "Controls" window.  If the cp input is a
            % NaN the function displays a checkerboard, if roi is not given the
            % function grabs one from the appropriate plot using the other included
            % information.
            
            if isnan(cp(1))
                roi=chex(1,21,21).*255;
            end
            
            x=round(cp(1,1)); % get an integer X point
            y=round(cp(1,2)); % get an integer Y point
            psize=app.SearchareawidthEditField.Value;
            
            if exist('roi','var')~=1 % don't have roi yet, go get it
                
                try
                    kids=get(app.handles{vnum+300},'Children'); % children of current axis
                    imdat=get(kids(end),'CData'); % read current image
                    roi=imdat(y-psize:y+psize,x-psize:x+psize,:);
                catch
                    return
                end
            end
            
            if isnan(roi(1))
                return
            end
            
            % update the roi viewer in the controls frame
            delete(app.SmallPlotAxes.Children);
            
            % scale roi to gamma
            gamma=app.VideoGammaSlider.Value;
            if size(roi,3)==1 % grayscale
                if min(min(roi))<0
                    roi=roi-min(min(roi));
                end
                roi2=(double(roi).^gamma).*(255/255^gamma);
                image('Parent',app.SmallPlotAxes,'Cdata',roi2,'CDataMapping','scaled');
                colormap(app.SmallPlotAxes,'gray');
            else % color
                %roi2=roi+(1-gamma)*128; % no ROI gamma scaling for color images, already
                %done to the image itself before display
                image('Parent',app.SmallPlotAxes,'Cdata',roi);
            end
            
            edgelen=size(roi,1);
            xlim(app.SmallPlotAxes,[1 edgelen]);
            ylim(app.SmallPlotAxes,[1 edgelen]);
            set(app.SmallPlotAxes,'ydir','reverse');
            
            % put a crosshair on the target image
            hold(app.SmallPlotAxes,'on')
            plot(app.SmallPlotAxes,[1;2*psize+1],[psize+1+cp(1,2)-y,psize+1+cp(1,2)-y],'r-');
            plot(app.SmallPlotAxes,[psize+1+cp(1,1)-x,psize+1+cp(1,1)-x],[1;2*psize+1],'r-');
            hold(app.SmallPlotAxes,'off')
            
        end
        
        function [] = addPoint(app)
            % function [] = addPoint(app)
            %
            % Add a point to the array & names array
            app.numpts=app.numpts+1; % update number of points
            app.pointNames{app.numpts}=sprintf('pt%.0f',app.numpts); 
            
            % update the drop-down menu            
            ptstring={};
            for i=1:app.numpts
                ptstring{i}=app.pointNames{i};
            end
            app.CurrentpointDropDown.ItemsData=1:app.numpts; % simple array of the number of points
            app.CurrentpointDropDown.Items=ptstring; % the string labels
            app.CurrentpointDropDown.Value=app.numpts; % the current active point
            
            % update the data matrices by adding the new columns
            app.xypts(:,(1:2*app.nvid)+(app.numpts-1)*2*app.nvid)=0;
            app.dltpts(:,3*app.numpts-2:3*app.numpts)=0;
            app.dltres(:,app.numpts)=0;
            
            % update the selected point
            app.sp=app.numpts;
            
            fullRedraw(app);
        end
        
        function [] = updateDLTdata(app,sp)

          % function [] = updateDLTdata(app,sp)
          %
          % Recomputes all 3D points from the 2D points, subframe offset,
          % undistortion info and DLT coefficients
          %
          % Inputs: uda - whole data bundle
          %         sp - points to recalculate as a row vector

          % Compute 3D coordinates + residuals
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %added dedistortion (Baier 1/16/06) (modified Hedrick 6/23/08)
          if app.dlt
            app.ExportDLTcoefficientsMenu.Enable=true;
            for i=sp

              % subframe interpolation
              for fr=1:size(app.xypts,1)
                try
                  xy=sp2full(app.xypts(fr-1:fr+1,(1:2*app.nvid)+(i-1)*2*app.nvid));
                  xyI=xy;
                  sfi=mod(full(app.offset(fr,:)),1); % subframe interpolation
                  skel=(-1:1)'; % interpolation sequence
                  for j=1:app.nvid % loop through each camera
                    ndx=find(isfinite(xy(:,j*2)));
                    if numel(ndx)>1
                      xyI(ndx,j*2-1:j*2)=interp1(skel(ndx),xy(ndx,j*2-1:j*2),skel(ndx)+sfi(j),'linear','extrap');
                    end
                  end
                  udist(fr,:)=xyI(2,:);
                catch % fallback to no interpolation
                  udist(fr,:)=sp2full(app.xypts(fr,(1:2*app.nvid)+(i-1)*2*app.nvid));
                end
              end

              % old version - no subframe interpolation
              % udist=sp2full(app.xypts(:,(1:2*app.nvid)+(i-1)*2*app.nvid));


              for j=1:size(udist,2)/2
                if isempty(app.camud{j})==false
                  udist(:,j*2-1:j*2)=applyTform(app.camud{j},udist(:,j*2-1:j*2));
                end
              end
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              [rawResults,rawRes]=dlt_reconstruct_v2(app.dltcoef,udist);
              app.dltpts(:,i*3-2:i*3)=full2sp(rawResults(:,1:3));
              app.dltres(:,i)=full2sp(rawRes);
            end
          end

        end

        function [s] = tsWinMakePDstring(app)

          % function [s] = tsWinMakePDstring(app)
          %
          % Function to create the pulldown menu string for the timeseries window
          % and then set it in the timeseries window

          s=[];

          if app.dlt
            s=[s,'X|Y|Z|error|'];
          end

          for i=1:app.nvid
            s=[s,'u',num2str(i),'|'];
            s=[s,'v',num2str(i),'|'];
          end
          s(end)=[];

          % pulldown menu for selecting what to display
          app.handles{603} = uicontrol(...
            'Parent',app.handles{600},'Units','characters','Position',...
            [46 1.5 11 2],'String',s,'Style','popupmenu',...
            'Value',1,'Tag','popupmenu2','Enable','on','Callback',...
            {@tsRedraw,app});

        end

        function []=storeUndo(app)
            
            % store data for 1 level of undo
            app.bak.numpts=app.numpts;
            app.bak.xypts=app.xypts;
            app.bak.dltpts=app.dltpts;
            app.bak.dltres=app.dltres;
            app.bak.pointNames=app.pointNames;
            
        end
        
        function [] = restoreUndo(app)
            
            app.numpts=app.bak.numpts;
            app.xypts=app.bak.xypts;
            app.dltpts=app.bak.dltpts;
            app.dltres=app.bak.dltres;
            app.pointNames=app.bak.pointNames;
            
            % update the drop-down menu
            ptstring={};
            for i=1:app.numpts
                ptstring(i)=app.pointNames(i);
            end
            app.CurrentpointDropDown.ItemsData=1:app.numpts;
            app.CurrentpointDropDown.Items=ptstring;
            app.CurrentpointDropDown.Value=min([app.sp,app.numpts]);
            
            
        end
        
        
        function updateTrackerMenu(app)
            % updates the tracker menu, listing cross-correlation and all
            % available 1-point trackers
            netNames={'cross-correlation'};
            netNumbers=[0];
            cnt=2;
            for i=1:numel(app.deepLearningNetworks)
                if app.deepLearningNetworks{i}{2}.type==3
                    netNames{cnt}=app.deepLearningNetworks{i}{2}.name;
                    netNumbers(cnt)=i;
                    cnt=cnt+1;
                end
            end
            app.TrackerDropDown.Items=netNames;
            app.TrackerDropDown.ItemsData=netNumbers;
            
        end
    end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
            % set location
            try
                %         desktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                %         desktopMainFrame = desktop.getMainFrame;
                %         b=desktopMainFrame.getBounds;
                %         % move
                %         mainAppPosition = app.DLTdv8appUIFigure.Position;
                %         app.DLTdv8appUIFigure.Position(1:2)=[b.x,b.y]+[10,30];
                movegui(app.DLTdv8appUIFigure); % test in Linux, Mac.  The above code does not work in Windows in some circumstances
            catch ME
                % nothing
            end
            
            % disable some warnings
            warning('off','images:inv_lwm:cannotEvaluateTransfAtSomeOutputLocations');
            
    end

    % Menu selected function: NewMenu
    function InitButtonCallback(app, event)
            
            % turn off some warnings
            warning off images:inv_lwm:cannotEvaluateTransfAtSomeOutputLocations
            
            % ask the user how many videos to load
            [app.nvid,ok]=...
                listdlg('PromptString','How many videos do you have?', ...
                'SelectionMode','single','ListString',{'1','2','3','4','5','6',...
                '7','8','9'}, 'Name', ...
                '# of videos','listsize',[160 80]);
            if isempty(ok) || ok==0
                app.MessageLabel.Value={'Initialization canceled, please try again.'};
                return
            end
            
            % create movie figures and axes - see the subfunction for all graphics
            % positioning, handles, etc.
            dvMakeMovieWindows(app);
            
            % grab the app handle array
            h = app.handles;
            
            % Browse for videos
            cwd=pwd; % store current directory
            for i=1:app.nvid
                
                % browse for each video file
                pause(0.01); % make sure that the uigetfile executed (MATLAB bug)
                [fname1,pname1] = uigetfile( ...
                    {'*.asf;*.avi;*.cin;*.cine;*.mp4;*.mrf;*.mov;*.wmv;*.cih;*.ASF;*.AVI;*.CIN;*.CINE;*.MP4;*.MRF;*.MOV;*.WMV;*.CIH;', ...
                    'All movie files (*.avi, *.asf, *.cin, *.cine, *.mp4, *.mrf, *.mov, *.wmv, *.cih)'; ...
                    '*.avi;*.AVI','AVI movies (*.avi)'; ...
                    '*.cin;*.CIN;*.cine;*.CINE','Phantom movies (*.cin,*.cine)'; ...
                    '*.mp4;*.MP4','mpeg4 movies (*.mp4)'; ...
                    '*.mrf;*.MRF','IDT Redlake movies (*.mrf)'; ...
                    '*.cih;*.CIH','Photron Files (*.cih)'; ...
                    '*.mov;*.MOV','Apple movies (*.mov)'; ...
                    '*.asf;*.ASF;*.wmv;*.WMV','Windows media movies (*.asf, *.wmv)'}, ...
                    sprintf('Pick movie file %.0d',i));
                
                pause(0.01); % make sure that the uigetfile executed (MATLAB bug)
                try
                    setappdata(h{300+i},'fname',[pname1,fname1]);
                    set(h{200+i},'Name',sprintf('Movie %.0f: %s',i,fname1));
                    
                    % change directory to where the last movie file was found
                    cd(pname1);
                catch
                    app.MessageLabel.Value={'Initialization failed.  Please try again.'};
                    for j=1:i
                        set(h{200+j},'deletefcn','');
                        close(h{200+j});
                    end
                    return
                end
            end
            cd(cwd); % restore original directory
            
            % Initialize the data arrays and some GUI elements
            %
            % set the slider size to first movie
            movie1info=mediaInfo(getappdata(h{301},'fname'));
            if movie1info.NumFrames>1
                app.FrameNumberSlider.Limits=[1 movie1info.NumFrames];
                app.FrameNumberSlider.MinorTicks=[1:round(movie1info.NumFrames/11):movie1info.NumFrames];
            else
                app.FrameNumberSlider.Limits=[1 2];
                %set(h{5),'Max',2,'Min',1,'Value',1,'SliderStep',[1 1]);
            end
            % setup the DLTpts, DLTres & offsets arrays
            app.dltpts=sparse(movie1info.NumFrames,3);
            app.dltres=sparse(movie1info.NumFrames,1);
            app.offset=sparse(movie1info.NumFrames,1);
            
            % setup the xypts array
            app.xypts=sparse(movie1info.NumFrames,2*app.nvid);
            
            % setup the numpts (Number of Points), current point & some other
            % options
            app.numpts=1; % number of points
            app.sp=1; % selected point
            app.pointNames{1}='pt1'; % name for the first point
            app.recentlysaved=1; % has this data been saved
            app.reloadVid=false; % force reload of all videos
            app.gapJump=0; % number of "missing data" frames to try and skip over
            cacheDepth=10; %
            for i=1:app.nvid
                app.cdataCache{i}=cell(cacheDepth,1);
                app.cdataCacheIdx{i}=nan(cacheDepth,1);
            end
            
            % plot the first images in each image window
            for i=1:app.nvid
                
                % make the axis of interest active and visible
                set(h{i+300},'Visible','on');
                
                % check for bad timing
                movname=getappdata(h{i+300},'fname');
                movieInfo=mediaInfo(movname);
                try % check for variableTiming
                    if movieInfo.variableTiming
                        beep
                        disp(['WARNING - Movie ',movname,' appears to have variable frame timing and may not read properly in MATLAB.'])
                        disp(['Please re-save or re-encode this movie in a format with fixed frame timing such as AVI'])
                        disp('-')
                    end
                catch
                end
                
                % set framerate
                set(h{450+i},'String',num2str(movieInfo.frameRate));
                
                % read the video frame
                [mov,fname]=mediaRead2(movname,1,true); % grab the 1st frame
                setappdata(h{i+300},'fname',fname); % set the name (or mmreader obj)
                
                % store the movie frame sizes
                app.movsizes(i,1)=size(mov.cdata,1);
                app.movsizes(i,2)=size(mov.cdata,2);
                app.movFrameRates(1,i)=movieInfo.frameRate;
                
                % plot the frame
                redlakeplot(app,mov.cdata,h{i+300});
                if size(mov.cdata,3)==1
                    colormap(h{i+300},gray(256));
                elseif size(mov.cdata,3)==3
                    app.DisplayincolorCheckBox.Value=true;
                end
                
                % cache the image
                app.cdataCache{i}=pushBuffer(app.cdataCache{i},mov.cdata);
                app.cdataCacheIdx{i}=pushBuffer(app.cdataCacheIdx{i},1);
                
                set(gca,'XTickLabel','');
                set(gca,'YTickLabel','');
                set(get(h{i+300},'Children'),'ButtonDownFcn',{@dvButtonDown,app});
                
                % store an initial drawVid value
                app.drawVid(i)=true;
                
                % create blank undistortion profile entries
                app.camd{i}=[];
                app.camud{i}=[];
            end
            app.movFrameRates(1,i)=movieInfo.frameRate;
            app.movFrameRateRatios=app.movFrameRates./app.movFrameRates(1);
            app.currframe(1:app.nvid)=1; % init current frame to 1 for all videos
            
            % setup DLT & multi-video options if appropriate
            if app.nvid==1
              dltyn=questdlg('You have only 1 camera, would you like to load 2D DLT coefficients?','Use DLT?', ...
                    'yes','no','yes');
            elseif app.nvid>1
                dltyn=questdlg('Are these cameras calibrated via DLT?','Use DLT?', ...
                    'yes','no','yes');
            end

            if strcmpi(dltyn,'yes')
              [fname1,pname1]=...
                uigetfile({'*.csv'},['Pick the DLT coefficients file.']);
              app.dltcoef=dlmread([pname1,fname1],',');
              app.dltcoef=app.dltcoef(:,1:app.nvid); % prune to # of videos
              app.dltFileName=[pname1,fname1]; % store path and filename to DLT coefficients

              app.dlt=true; % DLT mode enabled
              app.ExportDLTcoefficientsMenu.Enable=true;

              % enable 2D DLT mode if we got that sort of coefficient
              if size(app.dltcoef,1)==8
                app.dlt2d=true;
              end

              % attempt to automatically add tform files for videos
              calPrefix=fname1(1:end-12);
              d=dir([pname1,filesep,calPrefix,'*Tforms.mat']);
              if numel(d)==app.nvid % found matching set
                for udi=1:app.nvid
                  % load the file
                  load([pname1,filesep,d(udi).name]);
                  app.camd{udi}=camd;
                  app.camud{udi}=camud;
                  fprintf('Auto-Loaded undistortion transform matrix for camera%d.\n',udi);
                  set(h{425+udi},'String',['Undistortion file: ',d(udi).name]);
                end
              end
            else
              app.dlt=false; % User said No; DLT off
            end

            % enable setting 2D scale for single non-DLT video
            if app.dlt==false && app.nvid==1
              app.Set2DscaleandoriginMenu.Enable=true;
            end
            
            % if we're not doing DLT
            if app.dlt==false
                app.dltFileName=[]; % store null path and filename
                app.dltcoef=[]; % empty dlt coefficients
                %set(app.handles(53),'value',0); % turn off DLT visualization
            end
            
            % copy handles array back into app properties
            app.handles=h;
            
            % Create the timeseries window
            tsInitWindow_v1(app);
            
            % Enable appropriate UI elements in the app interface
            enableUIElements(app)
            
            % update the string fields
            updateTextFields(app);
            
            % call self to refresh video frames
            fullRedraw(app);
            
            %disp('InitButtonCallback end')
            app.initComplete=true;

            % call fullredraw again to finish the post-init setup
            fullRedraw(app);
    end

    % Value changed function: FrameNumberSlider, VideoGammaSlider
    function FrameNumberSliderValueChanged(app, event)
            %value = app.FrameNumberSlider.Value;
            fullRedraw(app);
    end

    % Value changed function: FrameNumberField
    function FrameNumberFieldValueChanged(app, event)
            value = app.FrameNumberField.Value;
            try
                value=round(value);
                if value>0 && value<=app.FrameNumberSlider.Limits(2)
                    app.FrameNumberSlider.Value=value;
                    fullRedraw(app);
                end
            catch ME
                % reset
                app.FrameNumberField.Value=app.currframe(app.lastvnum);
            end
    end

    % Button pushed function: AddapointButton
    function AddapointButtonPushed(app, event)
            addPoint(app)
    end

    % Value changed function: CurrentpointDropDown
    function CurrentpointDropDownValueChanged(app, event)
            %disp('CurrentPointDropDownValueChanged callback')
            value = app.CurrentpointDropDown.Value;
            app.sp=value; % update selected point
            
            pt=app.xypts(round(app.FrameNumberSlider.Value),(app.lastvnum*2-1:app.lastvnum*2)+(app.sp-1)*2*app.nvid);
            
            % update the magnified point view
            updateSmallPlot(app,app.handles,app.lastvnum,pt);
            
            % do a quick screen redraw
            quickRedraw(app,app.handles,app.sp,app.FrameNumberSlider.Value);
            
    end

    % Selection changed function: Show2DtracksonvideoimagesButtonGroup
    function Show2DtracksonvideoimagesButtonGroupSelectionChanged(app, event)
            %selectedButton = app.Show2DtracksonvideoimagesButtonGroup.SelectedObject;
            fullRedraw(app);
    end

    % Value changed function: SubtractbackgroundCheckBox
    function SubtractbackgroundCheckBoxValueChanged(app, event)
            %value = app.SubtractbackgroundCheckBox.Value;
            fullRedraw(app)
    end

    % Value changed function: DisplayincolorCheckBox
    function DisplayincolorCheckBoxValueChanged(app, event)
            %value = app.DisplayincolorCheckBox.Value;
            fullRedraw(app)
    end

    % Menu selected function: QuitMenu
    function QuitMenuSelected(app, event)
            
            % if initialization completed and there is data to save
            if app.recentlysaved==0
                savefirst= ...
                    questdlg('Would you like to save your project before you quit?', ...
                    'Save first?','yes','no','yes');
                pause(0.1); % make sure that the questdlg executed
                if strcmp(savefirst,'yes')
                    dvSaveproject(app);
                    
                else
                    app.recentlysaved=1; % mark the data saved to avoid a 2nd check
                end
            end
            
            % delete videos
            for i=1:app.nvid
                try
                    delete(app.handles{i+200});
                catch
                end
            end
            
            % delete time series figure
            try
                delete(app.handles{600});
            catch
            end
            
            % turn warnings back on
            warning('on','images:inv_lwm:cannotEvaluateTransfAtSomeOutputLocations');

            % delete subApps
            try
              for i=1:numel(app.subAppObjects)
                try
                  delete(app.subAppObjects{i});
                catch
                end
              end
            catch
            end
            
            % delete main figure
            try
                delete(app);
            catch
            end

            
    end

    % Menu selected function: ExportMenu
    function ExportMenuSelected(app, event)
            % export or save points to text format
            
            % get a place to save it
            pname=uigetdir(pwd,'Pick a directory to contain the output files');
            pause(0.1); % make sure that the uigetdir executed (MATLAB bug)
            
            % get a prefix
            [pfix,cncl]=savePrefixDlg(pname);
            if cncl==true
                return
            end
            
            % save as flat or sparse output files
            sType=questdlg('Save the data in sparse or flat format?', ...
                'Save format?','sparse','flat','sparse');
            
            % check and see if we should create and save confidence intervals
            if app.dlt
                % recompute 3D points (just in case...)
                updateDLTdata(app,1:app.numpts);
                
                CIs=questdlg(['Would you like to create 95% confidence inter',...
                    'vals? (This can take a while)'],'95% CIs?','yes','no','no');
                pause(0.1); % make sure that the questdlg executed (MATLAB bug)
                if strcmp(CIs,'yes')==1
                    minErrs=questdlg(['Would you like to enforce a minimum ',...
                        'digitizing error of 1 pixel?'],'minimum error?', ...
                        'yes','no','no');
                    if strcmp(minErrs,'yes')==1
                        minErr=1.5; % adjust extra gain here
                    else
                        minErr=0;
                    end
                    
                    % get data into a 2D array
                    tdata=app.xypts;
                    
                    % call the CI building function
                    [CI,tol,weights]=dlt_confidenceIntervalsSparse(app.dltcoef,tdata,...
                        minErr,app);
                    
                    % spline filtered data
                    if exist('spaps','file')~=2
                        disp(['DLTdv8: The spline toolbox is not available so'...
                            ' spline filtered data cannot be created.'])
                    else
                        % create filtered data;
                        xyzData=app.dltpts;
                        xyzData=dlt_splineInterpSparse(xyzData,'linear');
                        weights(isnan(weights))=0;
                        fData=dlt_splineFilterSparse(xyzData,tol,weights,0);
                    end
                end
            end
            
            % start the actual data save routines
            if strcmpi(sType,'flat')
                % test for existing files
                if exist([pname,filesep,pfix,'xyzpts.csv'],'file')~=0
                    overwrite=questdlg('Overwrite existing data?', ...
                        'Overwrite?','Yes','No','No');
                else
                    overwrite='Yes';
                end
                
                % get number of points
                numPts=size(app.dltpts,2)/3;
                
                % create headers (dltpts)
                dlth=cell(size(app.dltpts,2),1);
                for i=1:numPts
                    dlth{i*3-2}=sprintf('%s_X',app.pointNames{i});
                    dlth{i*3-1}=sprintf('%s_Y',app.pointNames{i});
                    dlth{i*3-0}=sprintf('%s_Z',app.pointNames{i});
                end
                
                % create headers (dltres)
                dlthr=cell(size(app.dltpts,2)/3,1);
                for i=1:size(app.dltpts,2)/3
                    dlthr{i}=sprintf('%s_dltres',app.pointNames{i});
                end
                
                % create headers (xypts)
                xyh=cell(app.nvid*numPts*2,1);
                for i=1:numPts
                    for j=1:app.nvid
                        xyh{(i-1)*app.nvid*2+(j*2-1)}=...
                            sprintf('%s_cam%s_U',app.pointNames{i},num2str(j));
                        xyh{(i-1)*app.nvid*2+(j*2)}=...
                            sprintf('%s_cam%s_V',app.pointNames{i},num2str(j));
                    end
                end
                
                % create headers (offset)
                offh=cell(app.nvid,1);
                for i=1:app.nvid
                    offh{i}=sprintf('cam%s_offset',num2str(i));
                end
                
                if strcmp(overwrite,'Yes')==1
                    % dltpts
                    f1=fopen([pname,filesep,pfix,'xyzpts.csv'],'w');
                    % header
                    for i=1:numel(dlth)-1
                        fprintf(f1,'%s,',dlth{i});
                    end
                    fprintf(f1,'%s\n',dlth{end});
                    
                    % data
                    for i=1:size(app.dltpts,1)
                        tempData=squeeze(sp2full(app.dltpts(i,:)));
                        for j=1:numel(tempData)-1
                            fprintf(f1,'%.6f,',tempData(j));
                        end
                        fprintf(f1,'%.6f\n',tempData(end));
                    end
                    fclose(f1);
                    
                    % xypts
                    f1=fopen([pname,filesep,pfix,'xypts.csv'],'w');
                    % header
                    for i=1:numel(xyh)-1
                        fprintf(f1,'%s,',xyh{i});
                    end
                    fprintf(f1,'%s\n',xyh{end});
                    % data
                    for i=1:size(app.xypts,1)
                        tempData=squeeze(sp2full(app.xypts(i,:)));
                        for j=1:numel(tempData)-1
                            fprintf(f1,'%.6f,',tempData(j));
                        end
                        fprintf(f1,'%.6f\n',tempData(end));
                    end
                    fclose(f1);
                    
                    % xyzres
                    f1=fopen([pname,filesep,pfix,'xyzres.csv'],'w');
                    % header
                    for i=1:numel(dlthr)-1
                        fprintf(f1,'%s,',dlthr{i});
                    end
                    fprintf(f1,'%s\n',dlthr{end});
                    % data
                    for i=1:size(app.dltres,1);
                        tempData=squeeze(sp2full(app.dltres(i,:)));
                        for j=1:numel(tempData)-1
                            fprintf(f1,'%.6f,',tempData(j));
                        end
                        fprintf(f1,'%.6f\n',tempData(end));
                    end
                    fclose(f1);
                    
                    % offsets
                    f1=fopen([pname,filesep,pfix,'offsets.csv'],'w');
                    % header
                    for i=1:numel(offh)-1
                        fprintf(f1,'%s,',offh{i});
                    end
                    fprintf(f1,'%s\n',offh{end});
                    % data
                    for i=1:size(app.offset,1);
                        tempData=squeeze(sp2full(app.offset(i,:)));
                        tempData(isnan(tempData))=0;
                        for j=1:numel(tempData)-1
                            fprintf(f1,'%.6f,',tempData(j));
                        end
                        fprintf(f1,'%.6f\n',tempData(end));
                    end
                    fclose(f1);
                    
                    if exist('CI','var')==1
                        % create headers (xyzCI)
                        CIh=cell(size(app.dltpts,2),1);
                        for i=1:size(app.dltpts,2)/3
                            CIh{i*3-2}=sprintf('%s_X+-CI',app.pointNames{i});
                            CIh{i*3-1}=sprintf('%s_Y+-CI',app.pointNames{i});
                            CIh{i*3-0}=sprintf('%s_Z+-CI',app.pointNames{i});
                        end
                        
                        f1=fopen([pname,filesep,pfix,'xyzCI.csv'],'w');
                        % header
                        for i=1:numel(CIh)-1
                            fprintf(f1,'%s,',CIh{i});
                        end
                        fprintf(f1,'%s\n',CIh{end});
                        % data
                        for i=1:size(CI,1)
                            for j=1:numel(CI(i,:))-1
                                fprintf(f1,'%.6f,',sp2full(CI(i,j)));
                            end
                            fprintf(f1,'%.6f\n',sp2full(CI(i,j+1)));
                        end
                        fclose(f1);
                    end
                    
                    if exist('fData','var')==1
                        % create filtered data header
                        filtH=cell(size(app.dltpts,2),1);
                        for i=1:size(app.dltpts,2)/3
                            filtH{i*3-2}=sprintf('%sfilt_X',app.pointNames{i});
                            filtH{i*3-1}=sprintf('%sfilt_Y',app.pointNames{i});
                            filtH{i*3-0}=sprintf('%sfilt_Z',app.pointNames{i});
                        end
                        
                        % save the data file
                        f1=fopen([pname,filesep,pfix,'xyzFilt.csv'],'w');
                        % header
                        for i=1:numel(filtH)-1
                            fprintf(f1,'%s,',filtH{i});
                        end
                        fprintf(f1,'%s\n',filtH{end});
                        % data
                        for i=1:size(fData,1)
                            for j=1:numel(fData(i,:))-1
                                fprintf(f1,'%.6f,',sp2full(fData(i,j)));
                            end
                            fprintf(f1,'%.6f\n',sp2full(fData(i,j+1)));
                        end
                        fclose(f1);
                    end
                    
                    app.recentlysaved=1;
                    msgString=['Data exported as ',[pname,filesep,pfix],'*.csv'];
                    app.MessageLabel.Value={msgString};
                    disp(msgString);
                end
            else % save in sparse format
                % test for existing files
                if exist([pname,filesep,pfix,'xyzpts.tsv'],'file')~=0
                    overwrite=questdlg('Overwrite existing data?', ...
                        'Overwrite?','Yes','No','No');
                else
                    overwrite='Yes';
                end
                
                if strcmpi(overwrite,'yes')
                    % xypts
                    sparseSave([pname,filesep,pfix,'xypts.tsv'],...
                        'Sparse format camera coordinates file',app.xypts);
                    
                    % xyzpts
                    sparseSave([pname,filesep,pfix,'xyzpts.tsv'],...
                        'Sparse format 3D coordinates file',app.dltpts);
                    
                    % xyzres
                    sparseSave([pname,filesep,pfix,'xyzres.tsv'],...
                        'Sparse format 3D triangulation residuals file',app.dltres);
                    
                    % offsets
                    sparseSave([pname,filesep,pfix,'offsets.tsv'],...
                        'Sparse format camera offsets file',app.offset);
                    
                    % confidence intervals
                    if exist('CI','var')==1
                        sparseSave([pname,filesep,pfix,'xyzCI.tsv'],...
                            'Sparse format confidence intervals file',CI);
                    end
                    
                    % confidence interval - smoothed data
                    if exist('fData','var')==1
                        sparseSave([pname,filesep,pfix,'xyzFilt.tsv'],...
                            'Sparse format CI-smoothed 3D data',fData);
                    end

                    % point names
                    f1=fopen([pname,filesep,pfix,'pointNames.tsv'],'w');
                    fprintf(f1,'%s\n','PointNames_to_go_with_DLTdv8a_sparsesave_arrays');
                    for i=1:numel(app.pointNames)
                      fprintf(f1,'%s\n',app.pointNames{i});
                    end
                    fclose(f1);

                    app.recentlysaved=1;
                    msgString=['Data exported as ',[pname,filesep,pfix],'*.tsv'];
                    app.MessageLabel.Value={msgString};
                    
                end
                
            end
    end

    % Menu selected function: LoadPointsMenu
    function LoadPointsMenuSelected(app, event)
            
            % find the xy points file
            [fname1,pname1]=...
                uigetfile({'*.csv;*.tsv',...
                '*xypts.csv or *xypts.tsv: flat or sparse DLTdv data';...
                '*.tsv','*xypts.tsv sparse DLTdv data'; ...
                '*.csv','*xypts.csv flat DLTdv data'});
            pause(0.1); % make sure that the uigetfile executed (MATLAB bug)
            pfix=[pname1,fname1];
            pfix(end-8:end)=[]; % remove the 'xypts.csv' portion
            
            if strcmpi(fname1(end-2:end),'csv')
                flatFormat=true;
                suffix='csv';
                sfix='*.csv';
            else
                flatFormat=false;
                suffix='tsv';
                sfix='*.tsv';
            end
            
            % load the exported xy points
            if flatFormat  
              tempData=dlmread([pfix,'xypts.csv'],',',1,0);
              tempTable=readtable([pfix,'xypts.csv']); 
              xypts_new=full2sp(tempData);
            else
              xypts_new=sparseRead([pfix,'xypts.tsv']);
            end

            % size check on new points
            if mod(size(xypts_new,2)/(2*app.nvid),1)~=0
              disp(['Point import failed, the number of points detected is',...
                ' not consistent with the number of videos in this project.'])
              fprintf('Detected %.0f columns, but the # of columns should be a multiple of %.0f\n',...
                size(xypts_new,2),(2*app.nvid));
              return
            end
            if size(xypts_new,1)~=size(app.xypts,1)
              disp(['The length of the imported points file does not match ',...
                'the number of video frames, exiting.'])
              fprintf('Loaded %.0f rows, should be %.0f\n',...
                size(xypts_new,1),size(app.xypts,1))
              return
            end

            % load the exported dlt points
            try
              if flatFormat
                tempData=dlmread([pfix,'xyzpts.csv'],',',1,0);
                % reshape to final app.dltpts size
                dltpts_new=full2sp(tempData);
              else
                dltpts_new=sparseRead([pfix,'xyzpts.tsv']);
              end

            catch
              fprintf('Failed to load xyz points from %s\n',...
                [pfix,'xyzpts.',suffix])
              dltpts_new=full2sp(zeros(size(xypts_new,1),3*size(xypts_new,2)/(app.nvid*2)));
              if app.dlt
                fprintf(' Try 3D menu --> Recompute 3D points\n')
              end
            end

            % load the exported dlt residuals
            try
              if flatFormat
                tempData=dlmread([pfix,'xyzres.csv'],',',1,0);
                dltres_new=full2sp(tempData); % reshape to app.dltres size
              else
                dltres_new=sparseRead([pfix,'xyzres.tsv']);
              end
            catch
              fprintf('Failed to load xyz residuals from %s\n',...
                [pfix,'xyzres.',suffix])
              dltres_new=full2sp(zeros(size(xypts_new,1),size(xypts_new,2)/(app.nvid*2)));
              if app.dlt
                fprintf(' Try 3D menu --> Recompute 3D points\n')
              end
            end

            % load the exported offset
            try
              if flatFormat
                offset_new=full2sp(dlmread([pfix,'offsets.csv'],',',1,0));
              else
                offset_new=sparseRead([pfix,'offsets.tsv']);
              end
            catch
              fprintf('Failed to load camera offsets from %s, creating empty array.\n',...
                [pfix,'offsets.',suffix])
              offset_new=full2sp(zeros(size(xypts_new,1),app.nvid));
            end


            % set offsets in the text boxes to their average (non-zero) values from
            % the offsets file, then warn the user
            for i=2:app.nvid
                idx=find(offset_new(:,i)~=0);
                if numel(idx)==0
                    % offset is zero
                    set(app.handles{325+i},'String','0');
                else
                    o2=round(inanmean(offset_new(idx,i)));
                    set(app.handles{325+i},'String',num2str(o2));
                end
            end
            msgbox(['WARNING - check the offset values, they may not be correct'...
                ,' for partially digitizied files.'], ...
                'Warning','warn','modal')
            
            % update the points menu
            app.numpts=size(xypts_new,2)/(2*app.nvid);
            % parse point names from tempTable variable names
            if flatFormat
              for i=1:app.numpts
                app.pointNames{i}=tempTable.Properties.VariableNames{1+(i-1)*2*app.nvid}(1:end-7);
              end
            else
              if exist([pfix,'pointNames.tsv'],"file")==2
                tempNames=readtable([pfix,'pointNames.tsv'],'filetype','text');
                for i=1:app.numpts
                  app.pointNames{i}=tempNames.PointNames_to_go_with_DLTdv8a_sparsesave_arrays{i};
                end
              else
                for i=1:app.numpts
                  app.pointNames{i}=sprintf('pt%.0f',i);
                end
              end
            end
            % update the drop-down menu            
            ptstring={};
            for i=1:app.numpts
                ptstring{i}=app.pointNames{i};
            end
            app.CurrentpointDropDown.ItemsData=1:app.numpts; % simple array of the number of points
            app.CurrentpointDropDown.Items=ptstring; % the string labels
            app.CurrentpointDropDown.Value=app.numpts; % the current active point
            
            % set selected point back to 1
            app.sp=1;
            app.CurrentpointDropDown.Value=1;

            % push in all the new arrays
            app.xypts=xypts_new;
            app.dltpts=dltpts_new;
            app.dltres=dltres_new;
            app.offset=offset_new;
            
            % call self to update the video fields
            fullRedraw(app);
            
            % print msg to console
            app.MessageLabel.Value={['Loaded points from ',pfix,sfix]};
            disp(['Loaded points from ',pfix,sfix])
    end

    % Close request function: DLTdv8appUIFigure
    function DLTdv8appUIFigureCloseRequest(app, event)
      try
        QuitMenuSelected(app, event)
      catch
      end
            
    end

    % Menu selected function: Recompute3DpointsMenu
    function Recompute3DpointsMenuSelected(app, event)
            app.MessageLabel.Value={'Recomputing all 3D coordinates.'};
            updateDLTdata(app,1:app.numpts); % update all 3D output
            fullRedraw(app);
    end

    % Menu selected function: LoadnewDLTcoefficientsMenu
    function LoadnewDLTcoefficientsMenuSelected(app, event)
            app.MessageLabel.Value={'Loading new DLT coefficients'};
            [filename, pathname] = uigetfile('*.csv', 'Load new coefficients?');
            if isempty(filename)==false
                app.dltcoef=importdata([pathname,filename]);
                app.dltFileName=[pathname,filename];
                  
                % additional initialization and changes if the app was not
                % in DLT mode before
                if app.dlt==false
                  app.dlt=true;
                  app.LoadnewDLTcoefficientsMenu.Enable=true;
                  app.Recompute3DpointsMenu.Enable=true;
                  app.ExportDLTcoefficientsMenu.Enable=true;
                  
                  % get and set the pulldown menu string
                  tsWinMakePDstring(app);

                  % set 2D vs 3D mode options
                  if size(app.dltcoef,1)==8 % 2D mode
                    app.dlt2d=true;
                  else
                    app.ShowDLTvisualfeedbackCheckBox.Enable=true;
                    app.DLTresidualthresholdEditField.Enable=true;
                    app.DLTresidualthresholdEditFieldLabel.Enable=true;
                    app.DLTresidualLabel.Enable=true;
                  end
                  
                  
                end
                
                
            end
            updateDLTdata(app,1:app.numpts); % update all DLT results
            fullRedraw(app)
    end

    % Callback function
    function ListofmouseandkeyboardcommandsMenu_2Selected(app, event)
            disp('See the online documentation at https://biomech.web.unc.edu/dltdv')
            app.MessageLabel.Value={'See the online documentation at https://biomech.web.unc.edu/dltdv'};
            web('https://biomech.web.unc.edu/dltdv')
    end

    % Menu selected function: SaveMenu
    function SaveMenuSelected(app, event)
      app.MessageLabel.Value={['Saving project...']};
      progressDialog = uiprogressdlg(app.DLTdv8appUIFigure,'Indeterminate','on',...
                        'Message','Saving project...',...
                        'Title','Please wait');
      
      if isempty(app.saveName)==false
        try
          dvSaveproject(app)
          app.MessageLabel.Value={[datestr(now),': Project saved as ',app.savePath,app.saveName]};
        catch ME
          app.MessageLabel.Value={['Save failed - ',ME.identifier]};
          rethrow(ME)
        end
      else
        app.SaveasMenuSelected();
      end
      delete(progressDialog)
        
    end

    % Menu selected function: SaveasMenu
    function SaveasMenuSelected(app, event)
      app.MessageLabel.Value={['Saving project as...']};
      progressDialog = uiprogressdlg(app.DLTdv8appUIFigure,'Indeterminate','on',...
                        'Message','Saving project as...',...
                        'Title','Please wait');
      
        % get file save name and location
      [filename, pathname] = uiputfile('*.mat', 'Save DLTdv8 project as?');
      if filename==0
        disp('Project save canceled')
        app.MessageLabel.Value={'Project save as canceled'};
        return
      end
      
      % setup filename with appended _dvProject suffix, but don't double-up the
      % suffix
      filename2 = filename(1:end-4);
      s=strfind(filename2,'_dvProject');
      if isempty(s)==false
        filename2=filename2(1:s-1);
      end
      filename2 = [filename2,'_dvProject.mat'];
      
      app.saveName=filename2;
      app.savePath=pathname;
      
      try
        dvSaveproject(app)
        app.MessageLabel.Value={[datestr(now),': Project saved as ',app.savePath,app.saveName]};
      catch ME
        app.MessageLabel.Value={['Save failed - ',ME.identifier]};
        rethrow(ME)
      end
      
      delete(progressDialog)
    end

    % Menu selected function: LoadMenu
    function LoadMenuSelected(app, event)
            %Function to load the whole DLTdv8 project from a mat-file

            progressDialog = uiprogressdlg(app.DLTdv8appUIFigure,'Indeterminate','on',...
                        'Message','Loading project...',...
                        'Title','Please wait');
            
            % disable project loading in the UI
            app.NewMenu.Enable=false;
            app.LoadMenu.Enable=false;

            % browse for the project file
            if verLessThan('MATLAB','24')==true
              [filename,pathname] = uigetfile({'*_dvProject.mat','DLTdv8 project'},...
                'Pick a *_dvProject.mat file to load');
            else
              [filename,pathname] = uigetfile({'*.mat','MATLAB data files'},...
                'Pick a *_dvProject.mat file to load');
            end
            if filename==0
                disp('Load project canceled')
                % enable project loading in the UI
                app.NewMenu.Enable=true;
                app.LoadMenu.Enable=true;
                return
            end
            
            % create the waitbar
            wh = waitbar(0,'foo');
            wh.Children.Title.Interpreter='none';
            wh.Children.Title.String=['Loading ',pathname,filesep,filename];
            pause(0.001); % allow drawing update
            
            % load the file
            load([pathname,filename],'udExport');
            waitbar(1/7,wh);
            
            % restore basic data values - i.e. public properties of the app
            fn=fieldnames(udExport.data);
            for i=1:numel(fn)
                eval(['app.',fn{i},'=udExport.data.',fn{i},';']);
            end

            % check for pointNames - may not exist in older data files
            if isempty(app.pointNames)
              for i=1:app.numpts
                app.pointNames{i}=sprintf('pt%.0f',i);
              end
            end
            
            % set the saveName and savePath to be the filename and pathname
            % used to load the file
            app.saveName=filename;
            app.savePath=pathname;
            
            % restore specific UI values
            app.VideoGammaSlider.Value = udExport.ui.gamma.props.value;
            app.DisplayincolorCheckBox.Value = udExport.ui.color.props.value;
            app.SubtractbackgroundCheckBox.Value = udExport.ui.background.props.value;
            app.FrameNumberField.Value = udExport.ui.frameText.props.value;
            app.FrameNumberSlider.Limits(2) = udExport.ui.frameSlider.props.Max;
            app.FrameNumberSlider.Limits(1) = udExport.ui.frameSlider.props.Min;
            app.FrameNumberSlider.Value = udExport.ui.frameSlider.props.value;
            
            app.AutotrackmodeDropDown.Value = udExport.ui.autoMode.props.value;
            %udExport.ui.autoPredictor.props.value = get(uda.handles(35),'value');
            app.TrackerThresholdEditField.Value = udExport.ui.autoThreshold.props.value;
            app.SearchareawidthEditField.Value = udExport.ui.autoSize.props.value;
            app.CurrentpointDropDown.Items = udExport.ui.currentPoint.props.items;
            app.CurrentpointDropDown.ItemsData=1:numel(app.CurrentpointDropDown.Items); % always a simple sequence
            
            % check for old-style point list data
            if isstr(udExport.ui.currentPoint.props.value)
              app.CurrentpointDropDown.Value = str2double(udExport.ui.currentPoint.props.value);

              % generate a list of point names because if the above is true
              % then they won't be present in the saved data file
              for j=1:udExport.data.numpts
                app.pointNames{j}=sprintf('pt%.0f',j);
              end
            else
              app.CurrentpointDropDown.Value = udExport.ui.currentPoint.props.value;
            end
            
            
            app.DLTresidualthresholdEditField.Value = udExport.ui.dltThreshold.props.value;
            app.ShowDLTvisualfeedbackCheckBox.Value = udExport.ui.dltFeedback.props.value;
            
            app.UpdateallvideosCheckBox.Value = udExport.ui.vidUpdate.props.value;
            
            app.SelectedpointButton.Value = udExport.ui.show2Dtracks.props.value1;
            app.NoneButton.Value = udExport.ui.show2Dtracks.props.value2;
            app.AllButton.Value = udExport.ui.show2Dtracks.props.value3;
            
            app.MarkercentroidsearchDropDown.Value = udExport.ui.findCentroid.props.value;
            
            try
              app.ForcemovieseekingMenu.Checked = udExport.ui.ForcemovieseekingMenu.Checked;
            catch
              % nothing - this property was added at version 8.5.0 and
                % may not exist in all saves
            end

            try
                app.NumofframesEditField.Value = udExport.ui.NumofframesEditField.props.value;
            catch ME
                % nothing - this property was added at version 8.1.7 and
                % may not exist in all saves
            end
            
            try
                app.FrameadvancestepsizeEditField.Value = udExport.ui.StepsizeEditField.props.value;
                app.BigstepsizeEditField.Value = udExport.ui.BigstepsizeEditField.props.value;
            catch ME
                % nothing - this property was added at version 8.2.7 and
                % may not exist in all saves
            end
            
            % restore secondary data
            app.currframe = udExport.currframe;
            try
                app.dataStores = udExport.dataStores;
            catch
            end
            try
                app.deepLearningNetworks = udExport.deepLearningNetworks;
            catch
            end
            
            % create empty image cache
            cacheDepth=10; %
            for i=1:app.nvid
                app.cdataCache{i}=cell(cacheDepth,1);
                app.cdataCacheIdx{i}=nan(cacheDepth,1);
            end
            waitbar(2/7,wh);
            
            % restore movies
            dvMakeMovieWindows(app,true);
            % make invisible for now
            for i=1:app.nvid
                set(app.handles{200+i},'visible','off');
            end
            waitbar(3/7,wh);
            
            for i=1:app.nvid
                if exist(udExport.video.files{i},'file')==2 % file exists
                    setappdata(app.handles{300+i},'fname',udExport.video.files{i});
                else
                    [fname1,pname1] = uigetfile( ...
                        {'*.asf;*.avi;*.cin;*.cine;*.mp4;*.mrf;*.mov;*.wmv;*.cih;*.ASF;*.AVI;*.CIN;*.CINE;*.MP4;*.MRF;*.MOV;*.WMV;*.CIH;', ...
                        'All movie files (*.avi, *.asf, *.cin, *.cine, *.mp4, *.mrf, *.mov, *.wmv, *.cih)'; ...
                        '*.avi;*.AVI','AVI movies (*.avi)'; ...
                        '*.cin;*.CIN;*.cine;*.CINE','Phantom movies (*.cin,*.cine)'; ...
                        '*.mp4;*.MP4','mpeg4 movies (*.mp4)'; ...
                        '*.mrf;*.MRF','IDT Redlake movies (*.mrf)'; ...
                        '*.cih;*.CIH','Photron Files (*.cih)'; ...
                        '*.mov;*.MOV','Apple movies (*.mov)'; ...
                        '*.asf;*.ASF;*.wmv;*.WMV','Windows media movies (*.asf, *.wmv)'}, ...
                        sprintf('Movie file %s (movie #%.0d) is missing, locate it if possible.',udExport.video.files{i},i));
                    setappdata(app.handles{300+i},'fname',[pname1,fname1]);
                    
                    % try and get general path fix [to-do]
                    % 
                end
                
                % set misc. properties for video windows
                set(app.handles{325+i},'string',udExport.video.offset{i});
                set(app.handles{425+i},'string',udExport.video.undistortString{i});
                set(app.handles{450+i},'string',udExport.video.frameRateString{i});
                
                set(gca,'XTickLabel','');
                set(gca,'YTickLabel','');
                set(get(app.handles{i+300},'Children'),'ButtonDownFcn',{@dvButtonDown,app});
                
            end
            waitbar(4/7,wh);
            
            % create & restore timeseries window
            tsInitWindow_v1(app);
            fn=fieldnames(udExport.tsui);
            for i=1:numel(fn)
                eval(sprintf('fn2=fieldnames(udExport.tsui.%s.props);',fn{i}));
                for j=1:numel(fn2)
                    eval(sprintf('set(app.handles{udExport.tsui.%s.handle},%s%s%s,udExport.tsui.%s.props.%s);',fn{i},char(39),fn2{j},char(39),fn{i},fn2{j}));
                end
            end
            % recreate pull-down menu to account for old version saves
            % where it was not properly created after moving a project from
            % 2D to 3D analysis
            % get the pulldown menu string
            tsWinMakePDstring(app);
            
            waitbar(5/7,wh);
            
            % enable UI elements
            enableUIElements(app);
            
            % convert video string fnames to video handle fnames
            for i=1:app.nvid
                [mov,fname]=mediaRead2(getappdata(app.handles{300+i},'fname'),1,true);
                setappdata(app.handles{i+300},'fname',fname); % set the name (or VideoReader obj)
            end
            
            % restore video images
            for i=1:app.nvid
                try
                    [mov]=mediaRead2(getappdata(app.handles{300+i},'fname'),udExport.currframe(i),true); % grab the current frame
                    redlakeplot(app,mov.cdata,app.handles{i+300});
                    colormap(app.handles{i+300},gray(256));
                catch
                    mov.cdata=chex(1,640,480);
                end
            end
            
            
            waitbar(6/7,wh);
            
            % update tracker menu
            app.updateTrackerMenu;
            app.TrackerDropDown.Value = udExport.ui.trackerType.props.value;
            
            % set initComplete
            app.initComplete=true;
            
            % do a full redraw
            app.reloadVid=true;
            app.UpdateallvideosCheckBox.Value=true;
            fullRedraw(app);
            app.UpdateallvideosCheckBox.Value = udExport.ui.vidUpdate.props.value;
            
            % make visible now
            for i=1:app.nvid
                set(app.handles{200+i},'visible','on');
            end
            
            waitbar(7/7,wh);
            
            % update saved status
            app.recentlysaved=true;
            
            % close waitbar
            close(wh)
            app.MessageLabel.Value={'Loaded ',pathname,filesep,filename};
            delete(progressDialog);
    end

    % Menu selected function: ManagedatastoresMenu
    function ManagedatastoresMenuSelected(app, event)
            try
                % bring to front
                figure(app.subAppObjects{1}.DatastoremanagerUIFigure);
                % move
                mainAppPosition = app.DLTdv8appUIFigure.Position;
                app.subAppObjects{1}.DatastoremanagerUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
            catch
                app.subAppObjects{1}=DataStoreManager(app);
            end
    end

    % Menu selected function: ManagenetworksMenu
    function ManagenetworksMenuSelected(app, event)
            try
                % bring to front
                figure(app.subAppObjects{2}.NetworkmanagerUIFigure);
                % move
                mainAppPosition = app.DLTdv8appUIFigure.Position;
                app.subAppObjects{2}.NetworkmanagerUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
            catch
                app.subAppObjects{2}=NetworkManager(app);
            end
    end

    % Menu selected function: ApplynetworkMenu
    function ApplynetworkMenuSelected(app, event)
            try
                % bring to front
                figure(app.subAppObjects{3}.NetworkapplicationinterfaceUIFigure);
                % move
                mainAppPosition = app.DLTdv8appUIFigure.Position;
                app.subAppObjects{3}.NetworkapplicationinterfaceUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
            catch
                app.subAppObjects{3}=NetworkApplicationInterface(app);
            end
    end

    % Menu selected function: LicenseMenu
    function LicenseMenuSelected(app, event)
            lic=importdata('DLTdv8a_license.txt');
            msgbox(lic)
    end

    % Menu selected function: VersionMenu
    function VersionMenuSelected(app, event)
            msgbox(['This is DLTdv8a version ',app.versionString])
    end

    % Menu selected function: CheckforanewversionMenu
    function CheckforanewversionMenuSelected(app, event)
            %v=webread('http://hedricklab.bio.unc.edu/dltdv8a_version.txt');
            %v=webread('https://sakai.unc.edu/access/content/user/thedrick/DLTdv/dltdv8a_version.txt');
            v=webread('https://raw.githubusercontent.com/tlhedrick/dltdv/master/DLTdv8a_internal/dltdv8a_version.txt');
            idx=strfind(v,'.');
            vMajor=str2num(v(1:idx(1)-1));
            vMinor=str2num(v(idx(1)+1:idx(2)-1));
            vPoint=str2num(v(idx(2)+1:end));
            
            if vMajor>app.versionMajor || (vMinor > app.versionMinor & vMajor==app.versionMajor) || (vPoint > app.versionPoint & vMajor==app.versionMajor & vMinor==app.versionMinor)
                ButtonName = questdlg('There is a newer version at http://biomech.web.unc.edu/dltdv', 'Version update?', 'OK','Go get it','OK');
                if strcmpi(ButtonName,'Go get it')
                    web('https://biomech.web.unc.edu/dltdv')
                end
            else
                msgbox('You are using the current version')
            end
    end

    % Menu selected function: ListofmouseandkeyboardcommandsMenu_2
    function ListofmouseandkeyboardcommandsMenuSelected(app, event)
      if exist('uihtml','file')==2
        f=uifigure('Name','Mouse and keyboard commands');
        p=f.Position;
        h=uihtml(f);
        h.Position=[5 5 p(3)-10 p(4)-10];
        h.HTMLSource='keyboard_and_mouse_manual.html';
      else
        web('http://hedricklab.bio.unc.edu/DLTdv8/keyboard_and_mouse_manual.html')
      end
    end

    % Menu selected function: OpenonlinemanualMenu_2
    function OpenonlinemanualMenu_2Selected(app, event)
            web('https://biomech.web.unc.edu/dltdv8_manual')
    end

    % Menu selected function: CheckgraphicscardMenu
    function CheckgraphicscardMenuSelected(app, event)
            n=gpuDeviceCount;
            if n==0
                msg{1,1}='No graphics card (GPU) detected.';
            else
                gInfo=gpuDevice;
                msg{1,1}=['GPU: ',gInfo.Name];
                msg{1,2}=['CUDA driver version: ',num2str(gInfo.DriverVersion)];
                msg{1,3}=['CUDA toolkit version: ',num2str(gInfo.ToolkitVersion)];
                msg{1,4}=[sprintf('Total RAM: %.1f GiB',gInfo.TotalMemory/1024^3)];
                msg{1,5}=['CUDA compute capability: ',num2str(gInfo.ComputeCapability)];
                if str2num(gInfo.ComputeCapability)<3
                    msg{1,6}=['This card is too old for Deep Learning acceleration'];
                end
            end
            msgbox(msg)
    end

    % Menu selected function: StartDLTcalibrationtoolMenu
    function StartcalibrationtoolMenuSelected(app, event)
            DLTcal8;
    end

    % Menu selected function: FlipDLTcoefficientsMenu
    function FlipDLTcoefficientsMenuSelected(app, event)

      if app.dlt2d==true
        app.MessageLabel.Value={'2D DLT coefficients cannot be flipped.'};
        return
      end

      app.MessageLabel.Value={'Flipping the DLT coefficients'};

      heights=app.movsizes(:,1)';
      app.dltcoef=dlt_invert(app.dltcoef,heights);

      updateDLTdata(app,1:app.numpts); % update all 3D points
      fullRedraw(app)
    end

    % Menu selected function: FlipverticalMenu
    function FlipverticalMenuSelected(app, event)
            for i=1:app.numpts
                for j=1:app.nvid
                    yOff=(i-1)*app.nvid*2;
                    y=sp2full(app.xypts(:,yOff+j*2));
                    yNew=abs(y-app.movsizes(j,1));
                    app.xypts(:,yOff+j*2)=full2sp(yNew);
                end
            end
            % call self to update the video fields
            fullRedraw(app);
            
            % print msg to console
            app.MessageLabel.Value={'Flipped the vertical axis position of all digitized points.'};
    end

    % Value changed function: ShowDLTvisualfeedbackCheckBox
    function ShowDLTvisualfeedbackCheckBoxValueChanged(app, event)
            fullRedraw(app)
    end

    % Value changed function: NumofframesEditField
    function NumofframesEditFieldValueChanged(app, event)
      %value = app.NumofframesEditField.Value;
      fullRedraw(app);
    end

    % Menu selected function: ExporttoworkspaceMenu
    function ExporttoworkspaceMenuSelected(app, event)
            udExport=dvMakeSaveBundle(app); 
            [~,okpressed]=export2wsdlg({'Export DLTdv8a data to workspace as:'},{'udExport'},{udExport});

            if okpressed
                app.MessageLabel.Value={'Exported DLTdv8a data to workspace.'};
            end

    end

    % Menu selected function: MetadataeditorMenu
    function MetadataeditorMenuSelected(app, event)
      try
        % bring to front
        figure(app.subAppObjects{4}.pointMetaEditorUIFigure);
        % move
        mainAppPosition = app.DLTdv8appUIFigure.Position;
        app.subAppObjects{4}.pointMetaEditorUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
      catch
        app.subAppObjects{4}=pointMetaEditor(app);
      end
    end

    % Menu selected function: Set2DscaleandoriginMenu
    function Set2DscaleandoriginMenuSelected(app, event)
      try
        % bring to front
        figure(app.subAppObjects{5}.set2DscaleandoriginUIFigure);
        % move
        mainAppPosition = app.DLTdv8appUIFigure.Position;
        app.subAppObjects{5}.set2DscaleandoriginUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
      catch
        app.subAppObjects{5}=set2Dscaleandorigin(app);
      end
    end

    % Menu selected function: ExportDLTcoefficientsMenu
    function ExportDLTcoefficientsMenuSelected(app, event)
      [fname,pname]=uiputfile('*.csv','Export the current DLT coefficients','exported_dltCoefs.csv');
      if fname==0 & pname==0 % user cancel
        return
      end
      
      if strcmpi(fname(end-3:end),'.csv') % check filename
        fname=[fname,'.cvs'];
      end

      % write the data
      dlmwrite([pname,filesep,fname],app.dltcoef,',');
    end

    % Menu selected function: ClearmoviecacheMenu
    function ClearmoviecacheMenuSelected(app, event)
      for i=1:numel(app.cdataCacheIdx)
        app.cdataCacheIdx{i}(:)=NaN;
      end
      for i=1:numel(app.cdataCache)
        for j=1:numel(app.cdataCache{i})
          app.cdataCache{i}{j}=[];
        end
      end
      disp('Cleared image cache')
      app.MessageLabel.Value={'Cleared image cache'};
    end

    % Menu selected function: ForcemovieseekingMenu
    function ForcemovieseekingMenuSelected(app, event)
      if app.ForcemovieseekingMenu.Checked==true
        app.ForcemovieseekingMenu.Checked=false;
      else
        app.ClearmoviecacheMenuSelected(app);
        app.ForcemovieseekingMenu.Checked=true;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.DLTdv8appUIFigure = uifigure('Visible', 'off');
      app.DLTdv8appUIFigure.Name = 'DLTdv8 app';
      app.DLTdv8appUIFigure.CloseRequestFcn = createCallbackFcn(app, @DLTdv8appUIFigureCloseRequest, true);
      app.ProjectMenu = uimenu(app.DLTdv8appUIFigure);
      app.ProjectMenu.Text = 'Project';
      app.NewMenu = uimenu(app.ProjectMenu);
      app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @InitButtonCallback, true);
      app.NewMenu.Tooltip = {'Start a new DLTdv8a project'};
      app.NewMenu.Text = 'New';
      app.LoadMenu = uimenu(app.ProjectMenu);
      app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
      app.LoadMenu.Tooltip = {'Load a previously saved DLTdv8a project'};
      app.LoadMenu.Text = 'Load';
      app.SaveMenu = uimenu(app.ProjectMenu);
      app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
      app.SaveMenu.Tooltip = {'Save the project to the file it was loaded from.'};
      app.SaveMenu.Enable = 'off';
      app.SaveMenu.Text = 'Save';
      app.SaveasMenu = uimenu(app.ProjectMenu);
      app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
      app.SaveasMenu.Tooltip = {'Save the project as a new file.'};
      app.SaveasMenu.Enable = 'off';
      app.SaveasMenu.Text = 'Save as';
      app.ExporttoworkspaceMenu = uimenu(app.ProjectMenu);
      app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoworkspaceMenuSelected, true);
      app.ExporttoworkspaceMenu.Tooltip = {'Export project data to the MATLAB workspace'};
      app.ExporttoworkspaceMenu.Enable = 'off';
      app.ExporttoworkspaceMenu.Text = 'Export to workspace';
      app.QuitMenu = uimenu(app.ProjectMenu);
      app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
      app.QuitMenu.Tooltip = {'Quit DLTdv8a'};
      app.QuitMenu.Text = 'Quit';
      app.PointsMenu = uimenu(app.DLTdv8appUIFigure);
      app.PointsMenu.Enable = 'off';
      app.PointsMenu.Text = 'Points';
      app.ExportMenu = uimenu(app.PointsMenu);
      app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
      app.ExportMenu.Tooltip = {'Save the DLTdv8a data in text format'};
      app.ExportMenu.Text = 'Export';
      app.LoadPointsMenu = uimenu(app.PointsMenu);
      app.LoadPointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPointsMenuSelected, true);
      app.LoadPointsMenu.Tooltip = {'Load DLTdv8a data from text format'};
      app.LoadPointsMenu.Text = 'Load';
      app.FlipverticalMenu = uimenu(app.PointsMenu);
      app.FlipverticalMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipverticalMenuSelected, true);
      app.FlipverticalMenu.Tooltip = {'Flips the vertical image coordinate of the points. This is needed after loading points from DLTdv7 or earlier.'};
      app.FlipverticalMenu.Text = 'Flip vertical';
      app.MetadataeditorMenu = uimenu(app.PointsMenu);
      app.MetadataeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @MetadataeditorMenuSelected, true);
      app.MetadataeditorMenu.Text = 'Metadata editor';
      app.DMenu = uimenu(app.DLTdv8appUIFigure);
      app.DMenu.Enable = 'off';
      app.DMenu.Text = '3D';
      app.LoadnewDLTcoefficientsMenu = uimenu(app.DMenu);
      app.LoadnewDLTcoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadnewDLTcoefficientsMenuSelected, true);
      app.LoadnewDLTcoefficientsMenu.Tooltip = {'Load a new set of 2D or 3D DLT coefficients'};
      app.LoadnewDLTcoefficientsMenu.Text = 'Load new DLT coefficients';
      app.Recompute3DpointsMenu = uimenu(app.DMenu);
      app.Recompute3DpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @Recompute3DpointsMenuSelected, true);
      app.Recompute3DpointsMenu.Tooltip = {'Force recomputation of all 3D points from the 2D points + DLT coefficients'};
      app.Recompute3DpointsMenu.Text = 'Recompute 3D points';
      app.FlipDLTcoefficientsMenu = uimenu(app.DMenu);
      app.FlipDLTcoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipDLTcoefficientsMenuSelected, true);
      app.FlipDLTcoefficientsMenu.Tooltip = {'Flips DLT coefficients from DLTdv7 and earlier so they work with DLTdv8 and its upper left origin image coordinate system'};
      app.FlipDLTcoefficientsMenu.Text = 'Flip DLT coefficients';
      app.DeepLearningMenu = uimenu(app.DLTdv8appUIFigure);
      app.DeepLearningMenu.Enable = 'off';
      app.DeepLearningMenu.Text = 'DeepLearning';
      app.ManagedatastoresMenu = uimenu(app.DeepLearningMenu);
      app.ManagedatastoresMenu.MenuSelectedFcn = createCallbackFcn(app, @ManagedatastoresMenuSelected, true);
      app.ManagedatastoresMenu.Tooltip = {'Create and manage datastores for training deep learning networks'};
      app.ManagedatastoresMenu.Text = 'Manage datastores';
      app.ManagenetworksMenu = uimenu(app.DeepLearningMenu);
      app.ManagenetworksMenu.MenuSelectedFcn = createCallbackFcn(app, @ManagenetworksMenuSelected, true);
      app.ManagenetworksMenu.Tooltip = {'Create and manage deep learning image tracking networks'};
      app.ManagenetworksMenu.Text = 'Manage networks';
      app.ApplynetworkMenu = uimenu(app.DeepLearningMenu);
      app.ApplynetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplynetworkMenuSelected, true);
      app.ApplynetworkMenu.Text = 'Apply network';
      app.CheckgraphicscardMenu = uimenu(app.DeepLearningMenu);
      app.CheckgraphicscardMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckgraphicscardMenuSelected, true);
      app.CheckgraphicscardMenu.Tooltip = {'Gets in formation on the current graphics card.'};
      app.CheckgraphicscardMenu.Text = 'Check graphics card';
      app.UtilitiesMenu = uimenu(app.DLTdv8appUIFigure);
      app.UtilitiesMenu.Text = 'Utilities';
      app.StartDLTcalibrationtoolMenu = uimenu(app.UtilitiesMenu);
      app.StartDLTcalibrationtoolMenu.MenuSelectedFcn = createCallbackFcn(app, @StartcalibrationtoolMenuSelected, true);
      app.StartDLTcalibrationtoolMenu.Tooltip = {'Start the DLTcal8 program for computing 2D or 3D DLT coefficients from a calibration image and frame'};
      app.StartDLTcalibrationtoolMenu.Text = 'Start DLT calibration tool';
      app.Set2DscaleandoriginMenu = uimenu(app.UtilitiesMenu);
      app.Set2DscaleandoriginMenu.MenuSelectedFcn = createCallbackFcn(app, @Set2DscaleandoriginMenuSelected, true);
      app.Set2DscaleandoriginMenu.Enable = 'off';
      app.Set2DscaleandoriginMenu.Text = 'Set 2D scale and origin';
      app.ExportDLTcoefficientsMenu = uimenu(app.UtilitiesMenu);
      app.ExportDLTcoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDLTcoefficientsMenuSelected, true);
      app.ExportDLTcoefficientsMenu.Enable = 'off';
      app.ExportDLTcoefficientsMenu.Text = 'Export DLT coefficients';
      app.ClearmoviecacheMenu = uimenu(app.UtilitiesMenu);
      app.ClearmoviecacheMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearmoviecacheMenuSelected, true);
      app.ClearmoviecacheMenu.Tooltip = {'Clears the cached video frames in case they contain bad information'};
      app.ClearmoviecacheMenu.Enable = 'off';
      app.ClearmoviecacheMenu.Text = 'Clear movie cache';
      app.ForcemovieseekingMenu = uimenu(app.UtilitiesMenu);
      app.ForcemovieseekingMenu.MenuSelectedFcn = createCallbackFcn(app, @ForcemovieseekingMenuSelected, true);
      app.ForcemovieseekingMenu.Tooltip = {'Forces a seek operation before each movie frame read.'};
      app.ForcemovieseekingMenu.Enable = 'off';
      app.ForcemovieseekingMenu.Text = 'Force movie seeking';
      app.HelpMenu = uimenu(app.DLTdv8appUIFigure);
      app.HelpMenu.Text = 'Help';
      app.ListofmouseandkeyboardcommandsMenu_2 = uimenu(app.HelpMenu);
      app.ListofmouseandkeyboardcommandsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ListofmouseandkeyboardcommandsMenuSelected, true);
      app.ListofmouseandkeyboardcommandsMenu_2.Tooltip = {'Show a list of mouse and keyboard commands'};
      app.ListofmouseandkeyboardcommandsMenu_2.Text = 'List of mouse and keyboard commands';
      app.OpenonlinemanualMenu_2 = uimenu(app.HelpMenu);
      app.OpenonlinemanualMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenonlinemanualMenu_2Selected, true);
      app.OpenonlinemanualMenu_2.Tooltip = {'Open the DLTdv8a online manual'};
      app.OpenonlinemanualMenu_2.Text = 'Open online manual';
      app.VersionMenu = uimenu(app.HelpMenu);
      app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
      app.VersionMenu.Tooltip = {'Display the DLTdv8a version'};
      app.VersionMenu.Text = 'Version';
      app.CheckforanewversionMenu = uimenu(app.HelpMenu);
      app.CheckforanewversionMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforanewversionMenuSelected, true);
      app.CheckforanewversionMenu.Tooltip = {'Check to see if there is a new DLTdv8a version'};
      app.CheckforanewversionMenu.Text = 'Check for a new version';
      app.LicenseMenu = uimenu(app.HelpMenu);
      app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
      app.LicenseMenu.Tooltip = {'Display the DLTdv8a software license'};
      app.LicenseMenu.Text = 'License';
      app.VideodisplaycontrolsPanel = uipanel(app.DLTdv8appUIFigure);
      app.VideodisplaycontrolsPanel.Title = 'Video display controls';
      app.DisplayincolorCheckBox = uicheckbox(app.VideodisplaycontrolsPanel);
      app.DisplayincolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayincolorCheckBoxValueChanged, true);
      app.DisplayincolorCheckBox.Tooltip = {'Display the video images in color if possible'};
      app.DisplayincolorCheckBox.Enable = 'off';
      app.DisplayincolorCheckBox.Text = 'Display in color';
      app.SubtractbackgroundCheckBox = uicheckbox(app.VideodisplaycontrolsPanel);
      app.SubtractbackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractbackgroundCheckBoxValueChanged, true);
      app.SubtractbackgroundCheckBox.Tooltip = {'Subtract the n-4 video frame from the current frame before displaying (if possible)'};
      app.SubtractbackgroundCheckBox.Enable = 'off';
      app.SubtractbackgroundCheckBox.Text = 'Subtract background';
      app.VideoGammaLabel = uilabel(app.VideodisplaycontrolsPanel);
      app.VideoGammaLabel.Enable = 'off';
      app.VideoGammaLabel.Text = {'Video'; 'Gamma'};
      app.VideoGammaSlider = uislider(app.VideodisplaycontrolsPanel);
      app.VideoGammaSlider.Limits = [0.1 2];
      app.VideoGammaSlider.MajorTicks = [];
      app.VideoGammaSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSliderValueChanged, true);
      app.VideoGammaSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
      app.VideoGammaSlider.Enable = 'off';
      app.VideoGammaSlider.Tooltip = {'Use the slider to change the video gamma'};
      app.VideoGammaSlider.Value = 1;
      app.FrameNumberSliderLabel = uilabel(app.VideodisplaycontrolsPanel);
      app.FrameNumberSliderLabel.Enable = 'off';
      app.FrameNumberSliderLabel.Text = {'Frame'; 'Number'};
      app.FrameNumberSlider = uislider(app.VideodisplaycontrolsPanel);
      app.FrameNumberSlider.MajorTicks = [];
      app.FrameNumberSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSliderValueChanged, true);
      app.FrameNumberSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
      app.FrameNumberSlider.Enable = 'off';
      app.FrameNumberSlider.Tooltip = {'Select the frame number in the video file'};
      app.Label = uilabel(app.VideodisplaycontrolsPanel);
      app.Label.Enable = 'off';
      app.Label.Text = '';
      app.FrameNumberField = uieditfield(app.VideodisplaycontrolsPanel, 'numeric');
      app.FrameNumberField.RoundFractionalValues = 'on';
      app.FrameNumberField.ValueDisplayFormat = '%11.0f';
      app.FrameNumberField.ValueChangedFcn = createCallbackFcn(app, @FrameNumberFieldValueChanged, true);
      app.FrameNumberField.Enable = 'off';
      app.FrameNumberField.Tooltip = {'Type in the frame number'};
      app.FrameNumberFieldLabel = uilabel(app.VideodisplaycontrolsPanel);
      app.FrameNumberFieldLabel.Enable = 'off';
      app.FrameNumberFieldLabel.Text = '/ NaN';
      app.UpdateallvideosCheckBox = uicheckbox(app.VideodisplaycontrolsPanel);
      app.UpdateallvideosCheckBox.Tooltip = {'Update all video windows at frame advance'};
      app.UpdateallvideosCheckBox.Enable = 'off';
      app.UpdateallvideosCheckBox.Text = 'Update all videos';
      app.UpdateallvideosCheckBox.Value = true;
      app.ShowDLTvisualfeedbackCheckBox = uicheckbox(app.VideodisplaycontrolsPanel);
      app.ShowDLTvisualfeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDLTvisualfeedbackCheckBoxValueChanged, true);
      app.ShowDLTvisualfeedbackCheckBox.Tooltip = {'Show the epipolar line and reprojected 3D location on the video images'};
      app.ShowDLTvisualfeedbackCheckBox.Enable = 'off';
      app.ShowDLTvisualfeedbackCheckBox.Text = 'Show DLT visual feedback';
      app.ShowDLTvisualfeedbackCheckBox.Value = true;
      app.Show2DtracksonvideoimagesButtonGroup = uibuttongroup(app.VideodisplaycontrolsPanel);
      app.Show2DtracksonvideoimagesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Show2DtracksonvideoimagesButtonGroupSelectionChanged, true);
      app.Show2DtracksonvideoimagesButtonGroup.Tooltip = {'Tip: the length of the time series shown is controlled by the width setting in the time series window'};
      app.Show2DtracksonvideoimagesButtonGroup.Title = 'Show 2D tracks on video images';
      app.NoneButton = uiradiobutton(app.Show2DtracksonvideoimagesButtonGroup);
      app.NoneButton.Enable = 'off';
      app.NoneButton.Tooltip = {'Show no 2D tracks, on the point location in the current frame'};
      app.NoneButton.Text = 'None';
      app.AllButton = uiradiobutton(app.Show2DtracksonvideoimagesButtonGroup);
      app.AllButton.Enable = 'off';
      app.AllButton.Tooltip = {'Show all 2D tracks'};
      app.AllButton.Text = 'All';
      app.SelectedpointButton = uiradiobutton(app.Show2DtracksonvideoimagesButtonGroup);
      app.SelectedpointButton.Enable = 'off';
      app.SelectedpointButton.Tooltip = {'Show the 2D track of the selected point only'};
      app.SelectedpointButton.Text = 'Selected point';
      app.SelectedpointButton.Value = true;
      app.offramesEditFieldLabel = uilabel(app.Show2DtracksonvideoimagesButtonGroup);
      app.offramesEditFieldLabel.Enable = 'off';
      app.offramesEditFieldLabel.Text = '# of frames';
      app.NumofframesEditField = uieditfield(app.Show2DtracksonvideoimagesButtonGroup, 'numeric');
      app.NumofframesEditField.Limits = [1 10000];
      app.NumofframesEditField.RoundFractionalValues = 'on';
      app.NumofframesEditField.ValueChangedFcn = createCallbackFcn(app, @NumofframesEditFieldValueChanged, true);
      app.NumofframesEditField.Enable = 'off';
      app.NumofframesEditField.Value = 100;
      app.FrameadvancestepsizeEditFieldLabel = uilabel(app.VideodisplaycontrolsPanel);
      app.FrameadvancestepsizeEditFieldLabel.Enable = 'off';
      app.FrameadvancestepsizeEditFieldLabel.Text = 'Frame advance step size';
      app.FrameadvancestepsizeEditField = uieditfield(app.VideodisplaycontrolsPanel, 'numeric');
      app.FrameadvancestepsizeEditField.Limits = [1 Inf];
      app.FrameadvancestepsizeEditField.RoundFractionalValues = 'on';
      app.FrameadvancestepsizeEditField.Enable = 'off';
      app.FrameadvancestepsizeEditField.Tooltip = {'The number of frames advanced by tracking or the ''f'' key'};
      app.FrameadvancestepsizeEditField.Value = 1;
      app.BigstepsizeEditFieldLabel = uilabel(app.VideodisplaycontrolsPanel);
      app.BigstepsizeEditFieldLabel.Enable = 'off';
      app.BigstepsizeEditFieldLabel.Text = 'Big step size';
      app.BigstepsizeEditField = uieditfield(app.VideodisplaycontrolsPanel, 'numeric');
      app.BigstepsizeEditField.Limits = [1 Inf];
      app.BigstepsizeEditField.RoundFractionalValues = 'on';
      app.BigstepsizeEditField.Enable = 'off';
      app.BigstepsizeEditField.Tooltip = {'The number of frames advanced by the ''F'' key or subtracted by the ''B'' key'};
      app.BigstepsizeEditField.Value = 50;
      app.PointandtrackingcontrolsPanel = uipanel(app.DLTdv8appUIFigure);
      app.PointandtrackingcontrolsPanel.Title = 'Point and tracking controls';
      app.SmallPlotAxes = uiaxes(app.PointandtrackingcontrolsPanel);
      app.SmallPlotAxes.XTick = [];
      app.SmallPlotAxes.XTickLabelRotationMode = 'manual';
      app.SmallPlotAxes.YTick = [];
      app.SmallPlotAxes.YTickLabelRotationMode = 'manual';
      app.SmallPlotAxes.ZTickLabelRotationMode = 'manual';
      app.CurrentpointDropDownLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.CurrentpointDropDownLabel.Enable = 'off';
      app.CurrentpointDropDownLabel.Text = 'Current point';
      app.CurrentpointDropDown = uidropdown(app.PointandtrackingcontrolsPanel);
      app.CurrentpointDropDown.Items = {'pt1'};
      app.CurrentpointDropDown.ItemsData = 1;
      app.CurrentpointDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentpointDropDownValueChanged, true);
      app.CurrentpointDropDown.Enable = 'off';
      app.CurrentpointDropDown.Tooltip = {'Select the current point here'};
      app.CurrentpointDropDown.Value = 1;
      app.AddapointButton = uibutton(app.PointandtrackingcontrolsPanel, 'push');
      app.AddapointButton.ButtonPushedFcn = createCallbackFcn(app, @AddapointButtonPushed, true);
      app.AddapointButton.Enable = 'off';
      app.AddapointButton.Tooltip = {'Add a new point'};
      app.AddapointButton.Text = 'Add a point';
      app.AutotrackmodeDropDownLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.AutotrackmodeDropDownLabel.Enable = 'off';
      app.AutotrackmodeDropDownLabel.Text = 'Autotrack mode';
      app.AutotrackmodeDropDown = uidropdown(app.PointandtrackingcontrolsPanel);
      app.AutotrackmodeDropDown.Items = {'off', 'auto-advance', 'semi-automatic', 'automatic', 'multi-track', 'trace mode'};
      app.AutotrackmodeDropDown.Enable = 'off';
      app.AutotrackmodeDropDown.Value = 'off';
      app.TrackerDropDownLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.TrackerDropDownLabel.Enable = 'off';
      app.TrackerDropDownLabel.Text = 'Tracker';
      app.TrackerDropDown = uidropdown(app.PointandtrackingcontrolsPanel);
      app.TrackerDropDown.Items = {'cross-correlation', 'Deep Learning Network'};
      app.TrackerDropDown.Enable = 'off';
      app.TrackerDropDown.Tooltip = {'Select between cross-correlation and Deep Learning trackers if Deep Learning functions are available'};
      app.TrackerDropDown.Value = 'cross-correlation';
      app.MarkercentroidsearchDropDownLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.MarkercentroidsearchDropDownLabel.Enable = 'off';
      app.MarkercentroidsearchDropDownLabel.Text = 'Marker centroid search';
      app.MarkercentroidsearchDropDown = uidropdown(app.PointandtrackingcontrolsPanel);
      app.MarkercentroidsearchDropDown.Items = {'none', 'black', 'white'};
      app.MarkercentroidsearchDropDown.Enable = 'off';
      app.MarkercentroidsearchDropDown.Value = 'none';
      app.TrackerThresholdEditFieldLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.TrackerThresholdEditFieldLabel.Enable = 'off';
      app.TrackerThresholdEditFieldLabel.Text = 'Tracker Threshold';
      app.TrackerThresholdEditField = uieditfield(app.PointandtrackingcontrolsPanel, 'numeric');
      app.TrackerThresholdEditField.Limits = [0 Inf];
      app.TrackerThresholdEditField.Enable = 'off';
      app.TrackerThresholdEditField.Value = 10;
      app.SearchareawidthEditFieldLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.SearchareawidthEditFieldLabel.Enable = 'off';
      app.SearchareawidthEditFieldLabel.Text = 'Search area width';
      app.SearchareawidthEditField = uieditfield(app.PointandtrackingcontrolsPanel, 'numeric');
      app.SearchareawidthEditField.Limits = [1 Inf];
      app.SearchareawidthEditField.RoundFractionalValues = 'on';
      app.SearchareawidthEditField.Enable = 'off';
      app.SearchareawidthEditField.Value = 16;
      app.DLTresidualthresholdEditFieldLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.DLTresidualthresholdEditFieldLabel.Enable = 'off';
      app.DLTresidualthresholdEditFieldLabel.Text = 'DLT residual threshold';
      app.DLTresidualthresholdEditField = uieditfield(app.PointandtrackingcontrolsPanel, 'numeric');
      app.DLTresidualthresholdEditField.Limits = [0 Inf];
      app.DLTresidualthresholdEditField.Enable = 'off';
      app.DLTresidualthresholdEditField.Value = 3;
      app.PointzoomviewLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.PointzoomviewLabel.Text = 'Point zoom view';
      app.LocationpredictorLabel = uilabel(app.PointandtrackingcontrolsPanel);
      app.LocationpredictorLabel.Enable = 'off';
      app.LocationpredictorLabel.Text = 'Location predictor';
      app.LocationpredictorDropDown = uidropdown(app.PointandtrackingcontrolsPanel);
      app.LocationpredictorDropDown.Items = {'Kalman', 'linear', 'stationary'};
      app.LocationpredictorDropDown.ItemsData = {'1', '2', '3'};
      app.LocationpredictorDropDown.Enable = 'off';
      app.LocationpredictorDropDown.Tooltip = {'Select between cross-correlation and Deep Learning trackers if Deep Learning functions are available'};
      app.LocationpredictorDropDown.Value = '1';
      app.MessagesPanel = uipanel(app.DLTdv8appUIFigure);
      app.MessagesPanel.Title = 'Messages';
      app.MessageLabel = uitextarea(app.MessagesPanel);
      app.MessageLabel.Editable = 'off';
      app.MessageLabel.Enable = 'off';
      app.MessageLabel.Tooltip = {'App messages appear here'};
      app.MessageLabel.Value = {'App messages appear here'};
      app.DLTresidualLabel = uilabel(app.MessagesPanel);
      app.DLTresidualLabel.Enable = 'off';
      app.DLTresidualLabel.Text = 'DLT residual:                            ';
      app.TrackerscoreLabel = uilabel(app.MessagesPanel);
      app.TrackerscoreLabel.Enable = 'off';
      app.TrackerscoreLabel.Text = 'Tracker score: ';
      % Show the figure after all components are created
      app.DLTdv8appUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DLTdv8a

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.DLTdv8appUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.DLTdv8appUIFigure)
    end
  end
end